classdef InSitu_BST_SoftwareV8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartProcessButton              matlab.ui.control.Button
        OpenFileLocationButton          matlab.ui.control.Button
        FileLoc                         matlab.ui.control.TextArea
        SaveFileLocationLabel           matlab.ui.control.Label
        StopProcessButton               matlab.ui.control.Button
        HPGUI_Group                     matlab.ui.container.TabGroup
        HP1_Tab                         matlab.ui.container.Tab
        HP1_Group                       matlab.ui.container.TabGroup
        Input                           matlab.ui.container.Tab
        ImpedanceAnalyzerCVLabel_1      matlab.ui.control.Label
        Freq_unit_1                     matlab.ui.control.DropDown
        FrequencyLabel                  matlab.ui.control.Label
        Frequency_1                     matlab.ui.control.NumericEditField
        PositiveVoltageVLabel           matlab.ui.control.Label
        VPos_1                          matlab.ui.control.NumericEditField
        NegativeVoltageVLabel           matlab.ui.control.Label
        VNeg_1                          matlab.ui.control.NumericEditField
        VACmVpkpkEditFieldLabel         matlab.ui.control.Label
        OSC_1                           matlab.ui.control.NumericEditField
        SweepSwitchLabel                matlab.ui.control.Label
        Sweep_1                         matlab.ui.control.RockerSwitch
        StepVoltagemVLabel              matlab.ui.control.Label
        VStep_1                         matlab.ui.control.NumericEditField
        ofAveragesDropDownLabel         matlab.ui.control.Label
        NOA_1                           matlab.ui.control.DropDown
        IntegrationTimeDropDownLabel    matlab.ui.control.Label
        ITM_1                           matlab.ui.control.DropDown
        ExperimentalFittingLabel_1      matlab.ui.control.Label
        t_offset_unit_1                 matlab.ui.control.DropDown
        t_inc_unit_1                    matlab.ui.control.DropDown
        THKnmEditFieldLabel             matlab.ui.control.Label
        THK_1                           matlab.ui.control.NumericEditField
        HeatTempCLabel                  matlab.ui.control.Label
        TempH_1                         matlab.ui.control.NumericEditField
        StressVoltageVLabel             matlab.ui.control.Label
        Bias_1                          matlab.ui.control.NumericEditField
        TimeOffsetLabel                 matlab.ui.control.Label
        TimeOffset_1                    matlab.ui.control.NumericEditField
        TimeIncLabel                    matlab.ui.control.Label
        dt_1                            matlab.ui.control.NumericEditField
        TotalIterationsLabel            matlab.ui.control.Label
        Iter_tot_1                      matlab.ui.control.NumericEditField
        IterationsMeasureLabel          matlab.ui.control.Label
        IterM_1                         matlab.ui.control.NumericEditField
        ItDataPointsStressLabel         matlab.ui.control.Label
        Itpts_1                         matlab.ui.control.NumericEditField
        PreStressVoltageVLabel          matlab.ui.control.Label
        PreBias_1                       matlab.ui.control.NumericEditField
        PreStressTimeminLabel           matlab.ui.control.Label
        PreBiasTime_1                   matlab.ui.control.NumericEditField
        CoolTempCLabel                  matlab.ui.control.Label
        TempC_1                         matlab.ui.control.NumericEditField
        HeatWaitTimesLabel              matlab.ui.control.Label
        HeatTime_1                      matlab.ui.control.NumericEditField
        CoolWaitTimesLabel              matlab.ui.control.Label
        CoolTime_1                      matlab.ui.control.NumericEditField
        P1Lamp_1                        matlab.ui.control.Lamp
        P2Lamp_1                        matlab.ui.control.Lamp
        P3Lamp_1                        matlab.ui.control.Lamp
        P4Lamp_1                        matlab.ui.control.Lamp
        P1_1                            matlab.ui.control.StateButton
        P2_1                            matlab.ui.control.StateButton
        P3_1                            matlab.ui.control.StateButton
        P4_1                            matlab.ui.control.StateButton
        ArduinoPinLabel_1               matlab.ui.control.Label
        AP1_1                           matlab.ui.control.NumericEditField
        AP2_1                           matlab.ui.control.NumericEditField
        AP3_1                           matlab.ui.control.NumericEditField
        AP4_1                           matlab.ui.control.NumericEditField
        ArduinoPinLabel_2               matlab.ui.control.Label
        P5Lamp_1                        matlab.ui.control.Lamp
        P6Lamp_1                        matlab.ui.control.Lamp
        P7Lamp_1                        matlab.ui.control.Lamp
        P8Lamp_1                        matlab.ui.control.Lamp
        P5_1                            matlab.ui.control.StateButton
        P6_1                            matlab.ui.control.StateButton
        P7_1                            matlab.ui.control.StateButton
        P8_1                            matlab.ui.control.StateButton
        AP5_1                           matlab.ui.control.NumericEditField
        AP6_1                           matlab.ui.control.NumericEditField
        AP7_1                           matlab.ui.control.NumericEditField
        AP8_1                           matlab.ui.control.NumericEditField
        PinStatesLabel_1                matlab.ui.control.Label
        P1Label_1                       matlab.ui.control.Label
        P2Label_1                       matlab.ui.control.Label
        P3Label_1                       matlab.ui.control.Label
        P4Label_1                       matlab.ui.control.Label
        P5Label_1                       matlab.ui.control.Label
        P6Label_1                       matlab.ui.control.Label
        P7Label_1                       matlab.ui.control.Label
        P8Label_1                       matlab.ui.control.Label
        PinReset_1                      matlab.ui.control.Button
        CV1Tab_1                        matlab.ui.container.Tab
        Export_CV_1                     matlab.ui.control.Button
        CV1_1                           matlab.ui.control.UIAxes
        Normalize_1                     matlab.ui.control.StateButton
        CV2_1                           matlab.ui.control.UIAxes
        CV3_1                           matlab.ui.control.UIAxes
        CV4_1                           matlab.ui.control.UIAxes
        CV2Tab_1                        matlab.ui.container.Tab
        Export_CV_2_1                   matlab.ui.control.Button
        CV5_1                           matlab.ui.control.UIAxes
        Normalize_2_1                   matlab.ui.control.StateButton
        CV6_1                           matlab.ui.control.UIAxes
        CV7_1                           matlab.ui.control.UIAxes
        CV8_1                           matlab.ui.control.UIAxes
        VFBFit1Tab_1                    matlab.ui.container.Tab
        byC2_1_1                        matlab.ui.control.UIAxes
        byC2_2_1                        matlab.ui.control.UIAxes
        byC2_3_1                        matlab.ui.control.UIAxes
        byC2_4_1                        matlab.ui.control.UIAxes
        Export_byC2_1                   matlab.ui.control.Button
        DerPeaks_1                      matlab.ui.control.StateButton
        UpdateFit_1                     matlab.ui.control.Button
        CFitMinEditFieldLabel           matlab.ui.control.Label
        CFitMin_1                       matlab.ui.control.NumericEditField
        FitRegion_1                     matlab.ui.control.StateButton
        SmoothCheckBox_1                matlab.ui.control.CheckBox
        CFitMaxEditFieldLabel           matlab.ui.control.Label
        CFitMax_1                       matlab.ui.control.NumericEditField
        VFBFit2Tab_1                    matlab.ui.container.Tab
        byC2_5_1                        matlab.ui.control.UIAxes
        byC2_6_1                        matlab.ui.control.UIAxes
        byC2_7_1                        matlab.ui.control.UIAxes
        byC2_8_1                        matlab.ui.control.UIAxes
        Export_byC2_2_1                 matlab.ui.control.Button
        DerPeaks_2_1                    matlab.ui.control.StateButton
        SmoothCheckBox_2_1              matlab.ui.control.CheckBox
        UpdateFit_2_1                   matlab.ui.control.Button
        FitRegion_2_1                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_2         matlab.ui.control.Label
        CFitMax_2_1                     matlab.ui.control.NumericEditField
        CFitMinEditFieldLabel_2         matlab.ui.control.Label
        CFitMin_2_1                     matlab.ui.control.NumericEditField
        ItTab_1                         matlab.ui.container.Tab
        It_1                            matlab.ui.control.UIAxes
        Export_It_1                     matlab.ui.control.Button
        VFBvsTimeTab_1                  matlab.ui.container.Tab
        ExportVFBtime_1                 matlab.ui.control.Button
        VFBtime_1                       matlab.ui.control.UIAxes
        TempvsTimeTab_1                 matlab.ui.container.Tab
        TempTime_1                      matlab.ui.control.UIAxes
        Export_TempTime_1               matlab.ui.control.Button
        Hotplate2Tab                    matlab.ui.container.Tab
        HP2_Group                       matlab.ui.container.TabGroup
        Input_2                         matlab.ui.container.Tab
        ImpedanceAnalyzerCVLabel_2      matlab.ui.control.Label
        Freq_unit_2                     matlab.ui.control.DropDown
        FrequencyLabel_2                matlab.ui.control.Label
        Frequency_2                     matlab.ui.control.NumericEditField
        PositiveVoltageVLabel_2         matlab.ui.control.Label
        VPos_2                          matlab.ui.control.NumericEditField
        NegativeVoltageVLabel_2         matlab.ui.control.Label
        VNeg_2                          matlab.ui.control.NumericEditField
        VACmVpkpkEditFieldLabel_2       matlab.ui.control.Label
        OSC_2                           matlab.ui.control.NumericEditField
        SweepSwitchLabel_2              matlab.ui.control.Label
        Sweep_2                         matlab.ui.control.RockerSwitch
        StepVoltagemVLabel_2            matlab.ui.control.Label
        VStep_2                         matlab.ui.control.NumericEditField
        ofAveragesDropDownLabel_2       matlab.ui.control.Label
        NOA_2                           matlab.ui.control.DropDown
        IntegrationTimeDropDownLabel_2  matlab.ui.control.Label
        ITM_2                           matlab.ui.control.DropDown
        ExperimentalFittingLabel_2      matlab.ui.control.Label
        t_offset_unit_2                 matlab.ui.control.DropDown
        t_inc_unit_2                    matlab.ui.control.DropDown
        THKnmEditFieldLabel_2           matlab.ui.control.Label
        THK_2                           matlab.ui.control.NumericEditField
        HeatTempCLabel_2                matlab.ui.control.Label
        TempH_2                         matlab.ui.control.NumericEditField
        StressVoltageVLabel_2           matlab.ui.control.Label
        Bias_2                          matlab.ui.control.NumericEditField
        TimeOffsetLabel_2               matlab.ui.control.Label
        TimeOffset_2                    matlab.ui.control.NumericEditField
        TimeIncLabel_2                  matlab.ui.control.Label
        dt_2                            matlab.ui.control.NumericEditField
        TotalIterationsLabel_2          matlab.ui.control.Label
        Iter_tot_2                      matlab.ui.control.NumericEditField
        IterationsMeasureLabel_2        matlab.ui.control.Label
        IterM_2                         matlab.ui.control.NumericEditField
        ItDataPointsStressLabel_2       matlab.ui.control.Label
        Itpts_2                         matlab.ui.control.NumericEditField
        PreStressVoltageVLabel_2        matlab.ui.control.Label
        PreBias_2                       matlab.ui.control.NumericEditField
        PreStressTimeminLabel_2         matlab.ui.control.Label
        PreBiasTime_2                   matlab.ui.control.NumericEditField
        CoolTempCLabel_2                matlab.ui.control.Label
        TempC_2                         matlab.ui.control.NumericEditField
        HeatWaitTimesLabel_2            matlab.ui.control.Label
        HeatTime_2                      matlab.ui.control.NumericEditField
        CoolWaitTimesLabel_2            matlab.ui.control.Label
        CoolTime_2                      matlab.ui.control.NumericEditField
        P1Lamp_2                        matlab.ui.control.Lamp
        P2Lamp_2                        matlab.ui.control.Lamp
        P3Lamp_2                        matlab.ui.control.Lamp
        P4Lamp_2                        matlab.ui.control.Lamp
        P1_2                            matlab.ui.control.StateButton
        P2_2                            matlab.ui.control.StateButton
        P3_2                            matlab.ui.control.StateButton
        P4_2                            matlab.ui.control.StateButton
        ArduinoPinLabel_1_2             matlab.ui.control.Label
        AP1_2                           matlab.ui.control.NumericEditField
        AP2_2                           matlab.ui.control.NumericEditField
        AP3_2                           matlab.ui.control.NumericEditField
        AP4_2                           matlab.ui.control.NumericEditField
        ArduinoPinLabel_2_2             matlab.ui.control.Label
        P5Lamp_2                        matlab.ui.control.Lamp
        P6Lamp_2                        matlab.ui.control.Lamp
        P7Lamp_2                        matlab.ui.control.Lamp
        P8Lamp_2                        matlab.ui.control.Lamp
        P5_2                            matlab.ui.control.StateButton
        P6_2                            matlab.ui.control.StateButton
        P7_2                            matlab.ui.control.StateButton
        P8_2                            matlab.ui.control.StateButton
        AP5_2                           matlab.ui.control.NumericEditField
        AP6_2                           matlab.ui.control.NumericEditField
        AP7_2                           matlab.ui.control.NumericEditField
        AP8_2                           matlab.ui.control.NumericEditField
        PinStatesLabel_2                matlab.ui.control.Label
        P1Label_2                       matlab.ui.control.Label
        P2Label_2                       matlab.ui.control.Label
        P3Label_2                       matlab.ui.control.Label
        P4Label_2                       matlab.ui.control.Label
        P5Label_2                       matlab.ui.control.Label
        P6Label_2                       matlab.ui.control.Label
        P7Label_2                       matlab.ui.control.Label
        P8Label_2                       matlab.ui.control.Label
        PinReset_2                      matlab.ui.control.Button
        CV1Tab_2                        matlab.ui.container.Tab
        Export_CV_1_2                   matlab.ui.control.Button
        CV1_2                           matlab.ui.control.UIAxes
        Normalize_1_2                   matlab.ui.control.StateButton
        CV2_2                           matlab.ui.control.UIAxes
        CV3_2                           matlab.ui.control.UIAxes
        CV4_2                           matlab.ui.control.UIAxes
        CV2Tab_2                        matlab.ui.container.Tab
        Export_CV_2_2                   matlab.ui.control.Button
        CV5_2                           matlab.ui.control.UIAxes
        Normalize_2_2                   matlab.ui.control.StateButton
        CV6_2                           matlab.ui.control.UIAxes
        CV7_2                           matlab.ui.control.UIAxes
        CV8_2                           matlab.ui.control.UIAxes
        VFBFit1Tab_2                    matlab.ui.container.Tab
        byC2_1_2                        matlab.ui.control.UIAxes
        byC2_2_2                        matlab.ui.control.UIAxes
        byC2_3_2                        matlab.ui.control.UIAxes
        byC2_4_2                        matlab.ui.control.UIAxes
        Export_byC2_1_2                 matlab.ui.control.Button
        DerPeaks_1_2                    matlab.ui.control.StateButton
        UpdateFit_1_2                   matlab.ui.control.Button
        CFitMinEditFieldLabel_3         matlab.ui.control.Label
        CFitMin_1_2                     matlab.ui.control.NumericEditField
        FitRegion_1_2                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_3         matlab.ui.control.Label
        CFitMax_1_2                     matlab.ui.control.NumericEditField
        SmoothCheckBox_3                matlab.ui.control.CheckBox
        VFBFit2Tab_2                    matlab.ui.container.Tab
        byC2_5_2                        matlab.ui.control.UIAxes
        byC2_6_2                        matlab.ui.control.UIAxes
        byC2_7_2                        matlab.ui.control.UIAxes
        byC2_8_2                        matlab.ui.control.UIAxes
        Export_byC2_2_2                 matlab.ui.control.Button
        DerPeaks_2_2                    matlab.ui.control.StateButton
        SmoothCheckBox_4                matlab.ui.control.CheckBox
        UpdateFit_2_2                   matlab.ui.control.Button
        FitRegion_2_2                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_4         matlab.ui.control.Label
        CFitMax_2_2                     matlab.ui.control.NumericEditField
        CFitMinEditFieldLabel_4         matlab.ui.control.Label
        CFitMin_4                       matlab.ui.control.NumericEditField
        ItTab_2                         matlab.ui.container.Tab
        It_2                            matlab.ui.control.UIAxes
        Export_It_2                     matlab.ui.control.Button
        VFBvsTimeTab_2                  matlab.ui.container.Tab
        ExportVFBtime_2                 matlab.ui.control.Button
        VFBtime_2                       matlab.ui.control.UIAxes
        TempvsTimeTab_2                 matlab.ui.container.Tab
        TempTime_2                      matlab.ui.control.UIAxes
        Export_TempTime_2               matlab.ui.control.Button
        HP3_Tab                         matlab.ui.container.Tab
        HP3_Group                       matlab.ui.container.TabGroup
        Input_3                         matlab.ui.container.Tab
        ImpedanceAnalyzerCVLabel_3      matlab.ui.control.Label
        Freq_unit_3                     matlab.ui.control.DropDown
        FrequencyLabel_3                matlab.ui.control.Label
        Frequency_3                     matlab.ui.control.NumericEditField
        PositiveVoltageVLabel_3         matlab.ui.control.Label
        VPos_3                          matlab.ui.control.NumericEditField
        NegativeVoltageVLabel_3         matlab.ui.control.Label
        VNeg_3                          matlab.ui.control.NumericEditField
        VACmVpkpkEditFieldLabel_3       matlab.ui.control.Label
        OSC_3                           matlab.ui.control.NumericEditField
        SweepSwitchLabel_3              matlab.ui.control.Label
        Sweep_3                         matlab.ui.control.RockerSwitch
        StepVoltagemVLabel_3            matlab.ui.control.Label
        VStep_3                         matlab.ui.control.NumericEditField
        ofAveragesDropDownLabel_3       matlab.ui.control.Label
        NOA_3                           matlab.ui.control.DropDown
        IntegrationTimeDropDownLabel_3  matlab.ui.control.Label
        ITM_3                           matlab.ui.control.DropDown
        ExperimentalFittingLabel_3      matlab.ui.control.Label
        t_offset_unit_3                 matlab.ui.control.DropDown
        t_inc_unit_3                    matlab.ui.control.DropDown
        THKnmEditFieldLabel_3           matlab.ui.control.Label
        THK_3                           matlab.ui.control.NumericEditField
        HeatTempCLabel_3                matlab.ui.control.Label
        TempH_3                         matlab.ui.control.NumericEditField
        StressVoltageVLabel_3           matlab.ui.control.Label
        Bias_3                          matlab.ui.control.NumericEditField
        TimeOffsetLabel_3               matlab.ui.control.Label
        TimeOffset_3                    matlab.ui.control.NumericEditField
        TimeIncLabel_3                  matlab.ui.control.Label
        dt_3                            matlab.ui.control.NumericEditField
        TotalIterationsLabel_3          matlab.ui.control.Label
        Iter_tot_3                      matlab.ui.control.NumericEditField
        IterationsMeasureLabel_3        matlab.ui.control.Label
        IterM_3                         matlab.ui.control.NumericEditField
        ItDataPointsStressLabel_3       matlab.ui.control.Label
        Itpts_3                         matlab.ui.control.NumericEditField
        PreStressVoltageVLabel_3        matlab.ui.control.Label
        PreBias_3                       matlab.ui.control.NumericEditField
        PreStressTimeminLabel_3         matlab.ui.control.Label
        PreBiasTime_3                   matlab.ui.control.NumericEditField
        CoolTempCLabel_3                matlab.ui.control.Label
        TempC_3                         matlab.ui.control.NumericEditField
        HeatWaitTimesLabel_3            matlab.ui.control.Label
        HeatTime_3                      matlab.ui.control.NumericEditField
        CoolWaitTimesLabel_3            matlab.ui.control.Label
        CoolTime_3                      matlab.ui.control.NumericEditField
        P1Lamp_3                        matlab.ui.control.Lamp
        P2Lamp_3                        matlab.ui.control.Lamp
        P3Lamp_3                        matlab.ui.control.Lamp
        P4Lamp_3                        matlab.ui.control.Lamp
        P1_3                            matlab.ui.control.StateButton
        P2_3                            matlab.ui.control.StateButton
        P3_3                            matlab.ui.control.StateButton
        P4_3                            matlab.ui.control.StateButton
        ArduinoPinLabel_1_3             matlab.ui.control.Label
        AP1_3                           matlab.ui.control.NumericEditField
        AP2_3                           matlab.ui.control.NumericEditField
        AP3_3                           matlab.ui.control.NumericEditField
        AP4_3                           matlab.ui.control.NumericEditField
        ArduinoPinLabel_2_3             matlab.ui.control.Label
        P5Lamp_3                        matlab.ui.control.Lamp
        P6Lamp_3                        matlab.ui.control.Lamp
        P7Lamp_3                        matlab.ui.control.Lamp
        P8Lamp_3                        matlab.ui.control.Lamp
        P5_3                            matlab.ui.control.StateButton
        P6_3                            matlab.ui.control.StateButton
        P7_3                            matlab.ui.control.StateButton
        P8_3                            matlab.ui.control.StateButton
        AP5_3                           matlab.ui.control.NumericEditField
        AP6_3                           matlab.ui.control.NumericEditField
        AP7_3                           matlab.ui.control.NumericEditField
        AP8_3                           matlab.ui.control.NumericEditField
        PinStatesLabel_3                matlab.ui.control.Label
        P1Label_3                       matlab.ui.control.Label
        P2Label_3                       matlab.ui.control.Label
        P3Label_3                       matlab.ui.control.Label
        P4Label_3                       matlab.ui.control.Label
        P5Label_3                       matlab.ui.control.Label
        P6Label_3                       matlab.ui.control.Label
        P7Label_3                       matlab.ui.control.Label
        P8Label_3                       matlab.ui.control.Label
        PinReset_3                      matlab.ui.control.Button
        CV1Tab_3                        matlab.ui.container.Tab
        Export_CV_1_3                   matlab.ui.control.Button
        CV1_3                           matlab.ui.control.UIAxes
        Normalize_1_3                   matlab.ui.control.StateButton
        CV2_3                           matlab.ui.control.UIAxes
        CV3_3                           matlab.ui.control.UIAxes
        CV4_3                           matlab.ui.control.UIAxes
        CV2Tab_3                        matlab.ui.container.Tab
        Export_CV_2_3                   matlab.ui.control.Button
        CV5_3                           matlab.ui.control.UIAxes
        Normalize_2_3                   matlab.ui.control.StateButton
        CV6_3                           matlab.ui.control.UIAxes
        CV7_3                           matlab.ui.control.UIAxes
        CV8_3                           matlab.ui.control.UIAxes
        VFBFit1Tab_3                    matlab.ui.container.Tab
        byC2_1_3                        matlab.ui.control.UIAxes
        byC2_2_3                        matlab.ui.control.UIAxes
        byC2_3_3                        matlab.ui.control.UIAxes
        byC2_4_3                        matlab.ui.control.UIAxes
        Export_byC2_1_3                 matlab.ui.control.Button
        DerPeaks_1_3                    matlab.ui.control.StateButton
        UpdateFit_1_3                   matlab.ui.control.Button
        CFitMinEditFieldLabel_5         matlab.ui.control.Label
        CFitMin_1_3                     matlab.ui.control.NumericEditField
        FitRegion_1_3                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_5         matlab.ui.control.Label
        CFitMax_1_3                     matlab.ui.control.NumericEditField
        SmoothCheckBox_5                matlab.ui.control.CheckBox
        VFBFit2Tab_3                    matlab.ui.container.Tab
        byC2_5_3                        matlab.ui.control.UIAxes
        byC2_6_3                        matlab.ui.control.UIAxes
        byC2_7_3                        matlab.ui.control.UIAxes
        byC2_8_3                        matlab.ui.control.UIAxes
        Export_byC2_2_3                 matlab.ui.control.Button
        DerPeaks_2_3                    matlab.ui.control.StateButton
        SmoothCheckBox_6                matlab.ui.control.CheckBox
        UpdateFit_2_3                   matlab.ui.control.Button
        FitRegion_2_3                   matlab.ui.control.StateButton
        CFitMaxEditFieldLabel_6         matlab.ui.control.Label
        CFitMax_2_3                     matlab.ui.control.NumericEditField
        CFitMinEditFieldLabel_6         matlab.ui.control.Label
        CFitMin_5                       matlab.ui.control.NumericEditField
        ItTab_3                         matlab.ui.container.Tab
        It_3                            matlab.ui.control.UIAxes
        Export_It_3                     matlab.ui.control.Button
        VFBvsTimeTab_3                  matlab.ui.container.Tab
        ExportVFBtime_3                 matlab.ui.control.Button
        VFBtime_3                       matlab.ui.control.UIAxes
        TempvsTimeTab_3                 matlab.ui.container.Tab
        TempTime_3                      matlab.ui.control.UIAxes
        Export_TempTime_3               matlab.ui.control.Button
        SaveDataAsTextAreaLabel         matlab.ui.control.Label
        DataFileName                    matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        dir % Description
        dtime % Description
        I % Description
        Arduino % Description
        HP % Description
        Therm % Description
        TC % Description
        TC_time % Description
        It_t % Description
        P % Description
        S % Description
    end
    
    methods (Access = public)
        
        function HW = InitHardware(app) %Function to Initialize Hotplate Serial Object
            for i=1:3 % For the 3 measurement units
                % Print waitbar
                message1=['Initializing Instruments:Hotplate ',num2str(i),'... Please Wait'];
                wb = waitbar(i/6,message1);               
                % Initialize hotplates
                COM_HP = ['COM',num2str(i+8)]; % CHECK THE PORT NUMBERS FOR EACH HOTPLATE
                delete(instrfind('Port',COM_HP)) %Delete existing object if there is one
                HW(i).HP = serial(COM_HP, 'BaudRate', 9600, 'DataBits',8,'StopBits',1); %Define serial connection to hotplate
                fopen(HW(i).HP); %Open Connection
                set(HW(i).HP, 'timeout',1); %Set timeout to 1 second
                
                % Initialize Arduinos
                message2=['Initializing Instruments:Arduino ',num2str(i),'... Please Wait'];
                waitbar(i/3,wb,message2);                
                COM_ARd = ['COM',num2str(i+5)]; % CHECK THE PORT NUMBERS FOR EACH ARDUINO
                delete(instrfind({'Port'},{COM_ARd})) %Delete existing object if there is one
                HW(i).Arduino = arduino(COM,'Uno','Libraries','SPI');  %Define arduino connection
                HW(i).Therm = spidev(Arduino,'D10','Bitrate',5e6); %Define connection through arduino to thermocouple
            end
            pause(0.5);
            close(wb)
        end
    
        function CloseHP(app, HW, MUnb, COM) %Function to Close/Clear Hotplate Serial Object
            fclose(HW(MUnb).HP); %Close object
            delete(HW(MUnb).HP); %Delete object in workspace
            clear(HW(MUnb).HP);
            delete(instrfind('Port',COM)) %Delete connection to communication port
        end
        
        function val = tInSec(app,UnitDrop,property) %Function to Convert Any Time Value to Seconds From Specified GUI Unit
            if (UnitDrop == "sec")
                val = property; % sec to sec
            elseif (UnitDrop == "min")
                val = 60*property; % min to sec
            elseif (UnitDrop == "hrs")
                val = 60*60*property; % hrs to sec
            elseif (UnitDrop == "days")
                val = 24*60*60*property; % hrs to sec
            end
        end
        
        function [Cpr,Rpr,Vr] = RunProgCV(app, Prog) %Function to Run CV Progam/Measurement
            delete(instrfind('Name','VISA-GPIB0-17')); %Delete Impedance Analyze visa object if it already exists
            v=visa('agilent', 'GPIB0::17::INSTR'); %Define visa object to Impedance Analyzer
            set(v, 'InputBufferSize', 64*1024); %Set data buffer size (important to read out all CV data)
            set(v,'Timeout',120); %Set visa timeout
            fopen(v); %Open visa
            drawnow;
            fprintf(v,Prog); %Print/Send program to Impedance Analyzer
            out=textscan(query(v, 'RUN'),'%c %s %s %s %s %s %s %s','headerlines', 4); % Run Program and Wait for Data Output
            fprintf(v,"FNC2"); %Gain-Phase Function (Switches DC Bias Off, by Going to Different Measurement Mode)
            
            unit = cell2mat(out{1,5}); %Analyzes unit factor from Impedance Anlyzer (IA) output
            mult = []; %Set multiplication factor to null
            
            for j=1:length(unit) %Parse through unit factor from output and define multiplacation factor array
                if (unit(j) == "f")
                    mult = [mult 1e-15 ];
                elseif (unit(j) == "p")
                    mult = [mult 1e-12 ];
                elseif (unit(j) == "n")
                    mult = [mult 1e-9 ];
                elseif (unit(i) == "u")
                    mult = [mult 1e-6 ];
                elseif (unit(i) == "m")
                    mult = [mult 1e-3 ];
                end
            end
            
            Cpr =str2double(out{1,4}); %Copy out capacitance (C) double array from (IA) output
            Cpr = Cpr(1:end-1).*mult'; %Multiplat C array with corresponding unit multiplication factor
            
            Vr = str2double(out{1,3}); %Copy out voltatge (V) double array from (IA) output
            Vr = Vr(1:end-1); %Remove character tail
            
            Rpr =str2double(out{1,6})*1e3; %Copy out resistance (R) double array from (IA) output (mult to get ohms)
            Rpr = Rpr(1:end-1); %Remove character tail
            fclose(v); %Close visa object
            delete(v); %Delete visa object
            clear v; %Clear object from workspace
        end
        
        %Function to Run DC Stress Bias using Keithley 2401
        function [I t TempTC temp_t] = RunBias(app, Vstress,tIstress,tstep,th,Ih,PinState,ArdP,LampSet,LampColor,IsPreBias,PreBiasPin)
            writeDigitalPin(app.Arduino,'D2',0) %Turn off relay switches to Impedance Analyzer (IA)
            for p = 1:length(PinState) %Parse through all  pins
                writeDigitalPin(app.Arduino,char("D"+num2str(ArdP(p))),0); %Set all abailable pins to 0 or off
            end
            if(IsPreBias) %If prebias step
                writeDigitalPin(app.Arduino,char("D"+num2str(ArdP(PreBiasPin))),1); %Set PreBias pin to 1 or on
            else %If regular stress step
                for p = 1:length(PinState) %Parse through all available pins
                    if(PinState) %if pin is available
                        writeDigitalPin(app.Arduino,char("D"+num2str(ArdP(p))),1); %Set all preselected experimental pins to 1
                    end
                end
            end
            delete(instrfind('Name','VISA-GPIB0-25')) %Delete visa object to Keithley 2401 (K2401) if found
            k=visa('agilent', 'GPIB0::25::INSTR'); %Define visa connection to K2401
            set(k, 'InputBufferSize', 64*1024); %Set data buffer size (important to read out all current data)
            fopen(k) %Open visa object
            set(k,'Timeout',10); %Set timeout
            
            fprintf(k, '*RST') %Reset K2401
            fprintf(k, ":OUTP:SMOD HIMP") %%Sets High Impedance Mode
            
            fprintf(k, ':ROUT:TERM REAR') %Set I/O to Rear Connectors
            fprintf(k, ':SENS:FUNC:CONC OFF') %Turn Off Concurrent Functions
            fprintf(k, ':SOUR:FUNC VOLT') %Voltage Source Function
            
            fprintf(k, ":SENSE:FUNC 'CURR:DC'") %DC Current Sense Function
            fprintf(k, ":SENSE:CURR:PROT .105") %Set Compliance Current to 105 mA
            
            fprintf(k, ":SOUR:VOLT "+Vstress) %Source Bias Voltage
            fprintf(k, ':SOUR:VOLT:MODE FIX') %Set Voltage Source Sweep Mode
            fprintf(k, ":SOUR:DEL .1") %100ms Source Delay
            
            fprintf(k, ":FORM:ELEM CURR") %Select Data Collecting Item Current
            fprintf(k, ":OUTP ON") %Turn On Source Output
            
            t=[];
            tcurr = [];
            I = [];
            
            for j = 1:tstep:tIstress %For every timestep until maximum stess time
                pause(tstep-0.327592); %Pause for timestep duration during high bias
                I = [I str2double(strsplit(query(k, ":READ?"),','))']; %Trigger Measurement, Request Data After Timestep Wait
                tcurr = [tcurr toc]; %Increment time for current array
                temp_t = toc;
                TempTC = getTC(app); %Get thrmocouple temperature
                
                plot(app.It_1,tcurr/3600,I,'ko-','LineWidth',2) %Add History of Time & Plot Current Vs. Time
                plot(app.TempTime_1,temp_t/3600,TempTC,'-o','LineWidth',2,'Color',[1,.4,0]) %Plot/Update thermocouple temperature vs time
            end
            t = tcurr;
            fprintf(k, ":OUTP OFF") %Turn Off Source Output
            delete(k) %Delete K2401 Object
            clear k %Cleare K2401 Object from Workspace
            
            for p = 1:length(PinState) %Turn off all available pins
                writeDigitalPin(app.Arduino,char("D"+num2str(ArdP(p))),0); %Set all pins to 0 or off
            end
        end
        
        function [Cfit,V,Vfb,fitfail,FitRegion_1] = VFBfitN(app, Cp,V,m,byC2Plot) %Flatband Linear Fitting Function
            Cfit = 1./(Cp./max(Cp)).^2; %Define normallized 1/C^2
            Vfbfit = [];
            Vfb = [];
            
            fitfail = 0; %Set fit boolean to 0 (Fit hasn't failed yet)
            in1 = find((V(:,1)<4)); %Concatenate data before 4V (Assuming Vfb is below this)
            V = V(in1,:); %Concatenate data before 4V (Assuming Vfb is below this)
            Cfit = Cfit(in1,:); %Concatenate data before 4V (Assuming Vfb is below this)
            
            FitRegion_1 = [];
            set(app.byC2_1_1, 'ColorOrder', jet(2*(app.Iter_tot_1.Value+1))) %Set fit plot colororder
            for j = 1:m %For every measurement m
                %Define region to fit based on Cmin & Cmax input
                in2 = find((Cfit(:,j)<app.CFitMax_1.Value & Cfit(:,j)>app.CFitMin_1.Value));
                Vfbfit = V(in2,j); %Limit voltage to fitting range
                %Cfit(:,i) = smooth(Cfit(:,i));
                try
                    f = fit(Vfbfit,Cfit(in2,j),'poly1'); %Fit 1/C^2 vs. V curve with polynomial fit
                    CfbFit = feval(f,V(:,j)); %Evaluate fit
                    plot(byC2Plot,V(:,j),Cfit(:,j),'LineWidth',1.5); %Plot 1/C^2 vs. Voltage
                    hold(byC2Plot,'on')
                    plot(byC2Plot,V(:,j),CfbFit, 'k --','LineWidth',1.5); %Plot fitted polynomial line
                    Vfb =[-f.p2/f.p1 Vfb ]; %Get flatband from y-intercept
                catch
                    fitfail = 1; %Set fitfail boolean to true (fit has failed)
                    hold(app.byC2_1_1,'off');
                    display("Couldn't fit for Flatband Voltage")
                end
            end
            app.byC2_1_1.YLim = [0 app.CFitMax_1.Value*2]
            hold(app.byC2_1_1,'off')
        end
        
        function PlotCV(app, C,V,Plot) %Function to plot CV curve & temperaure
            plot(Plot,V,C.*1e12,'LineWidth',2); %Plots regular C vs. V
            Plot.Title.String = "Capacitance Vs. Voltage (Bias_1 = "+app.Bias_1.Value+"V, T = "+app.TempH_1.Value+"C)";
            Plot.YLabel.String = 'Capacitance (pF)';
            
            TempTC = getTC(app); % Record Temperature (C)
            temp_t = toc; % Record time (s)
            
            hold(app.TempTime_1,'on')
            plot(app.TempTime_1,temp_t/3600,TempTC,'-o','LineWidth',2,'Color',[1,.4,0])
            app.TC = [TempTC app.TC]; %Record temperature values
            app.TC_time = [temp_t app.TC_time]; %Record temperature time values
        end
        
        function Success = setHPTemp(app,HP,T) %Function to set hotplate temperature
            %Set temperature devided into to values high temp (HT) & low temp (LT)
            %To be read in by hotplate
            
            HT = floor(T/25.6); %Set high temp value
            LT = mod(T,25.6)*10; %Set low temp value
            
            CumSum = 178+HT+LT; %Obtain checksum value
            if (CumSum > 255) %If checksum is more than hexadecimal limit (255)
                CumSum = CumSum-256; %Checksum needs to be reset by subtracting 256
            end
            
            arry = [254,178,HT,LT,0, CumSum]; %Serial bit/byte to send to hotplate
            for j=1:length(arry) %Iterature through serial array with 50ms waitime
                fwrite(HP,arry(j),'uint8'); %Write to hotplate
                pause(.05)
            end
            out = fread(HP); %Read out hotplate response
            
            if isempty(out) %If not responce
                display("Temperature Set Not Successful")
                Success = 0;
            else
                Success = 1;
                display("Temperature Set!")
            end
        end
        
        function [TR,SR,TS,SS,out] =  getHPParam(app,HP) %Function to read out parameters from hotplate (not currently used)
            arry = [254,162,0,0,0,162]; %Read hexadecimal byte serial command array
            fwrite(HP,[254,162,0,0,0,162],'uint8') %Write read command
            out = (fread(HP))';
            
            SS = [];
            SR = [];
            TS = [];
            TR = [];
            
            if isempty(out) %If no response
                display("Not Successful")
                Success = 0;
            else
                HSS = out(3)*25.6; %High Set Speed
                LSS = out(4)*.1; %Low Set Speed
                SS = HSS+LSS; %Set Speed
                
                HSR = out(5)*25.6; %High Real Speed
                LSR = out(6)*.1; %Low Real Speed
                SR = HSR+LSR; %Real Speed
                
                HTS = out(7)*25.6; %High Set Temperature
                LTS = out(8)*.1; %Low Set Temperature
                TS = HTS+LTS; %Set Temperature
                
                HTR = out(9)*25.6; %High Real Temperature
                LTR = out(10)*.1; %Low Real Temperature
                TR = HTR+LTR; %Real Temperature
                Success = 1;
                display("Temperature Obtained! Temp = "+TR+"C")
            end
        end
        
        function TempC_1 = getTC(app,HPnb)
            % Find which hotplate to check
            switch HPnb
                case 1
                    app_Therm=app.Therm.HP1;
                case 2
                    app_Therm=app.Therm.HP2;
                case 3
                    app_Therm=app.Therm.HP3;
            end
            
            dataIn = [1 1]; %write 2 pieces of info in order to receive 2 pieces of info
            dataOut = writeRead(app_Therm,dataIn,'uint16'); %uint16 is the largest integer you can use with this function therefore you need 2
            bytepack=uint32(dataOut(1)); %since there are two separate pieces you need to combine them to a 32-bit integer
            bytepack=bitshift(bytepack,16); %data(1) is the 1st half and is converted to uint32 and the data is shifted to the correct side.
            z = bitor(bytepack,uint32(dataOut(2))); %data(2) is the 2nd half and bitor() is used to place this into the correct side of uint32
            z = dec2bin(z,32); %create a 32-bit binary number
            TempC_1 = bin2dec(z(2:14))*0.25;  %from the MAX31855 datasheet, bits 18-31 is the temperature in celcius. These number are referenced backwards in matlab.
        end
        
        function [TempTC temp_t] = WaitPlotTemp(app,TempThresh,Err,WaitTime_s) %Wait Function to Measure/Plot Temperature
            TempTC = getTC(app); %Get current thermocouple temperature
            temp_t = toc; %Get current process time
            
            while(getTC(app)> TempThresh+Err || getTC(app)<TempThresh-Err) %While temperture is out of desired range, wait & plot
                TempTC =  [getTC(app) TempTC]; % Record Temperature (C)
                temp_t = [toc temp_t]; % Record time (s)
                plot(app.TempTime_1,temp_t/3600,TempTC,'-o','LineWidth',2,'Color',[1,.4,0]) %Plot thermocouple temperature vs. time
                pause(WaitTime_s); % Wait specified time
            end
        end
        
        %Convert Function from Desired Temperature Thermocouple to Hotplate Set Temperature Based on Calibration Fit
        function Tnew = CorrectT(app,T)
            a1 = 0.001240094001752;
            a2 = 0.938131307698908;
            b = 8.511123118723250;
            Tnew = a1*T^2+a2*T+b; %Calibration fit determines how to set hotplate to get desired thermocouple temperature to within +/- 1C
        end
        
        % RunInterCV perform a CV measurement on each pin that has been set to measure by the user.
        % It returns a flag "meas_flag" equals to 1 after a measurement has been completed. The flag is used by
        function [th Ih meas_flag] = RunIterCV(app,V,Progam,PreBias_1,PreBiasTime_1,th,Ih,PinState,ArdP,LampSet,LampColor,CVPlots,PlotCVby2,HPnb) %Run Iterative CV Measurement
            switch HPnb
                case 1
                    app_HP=app.HP1;
                    app_Arduino=app.Arduino_HP1;
                    %%%%%%% add here other variables related to HP1 needed in this function
                case 2
                    app_HP=app.HP2;
                    app_Arduino=app.Arduino_HP2;
                case 3
                    app_HP=app.HP3;
                    app_Arduino=app.Arduino_HP3;
            end
            
            IterM_1 = app.IterM_1.Value; %Amount of repeated measurements per stress iteration
            for p = 1:length(PinState) %For each pin
                for j = 1:IterM_1 %For each repeated measure
                    if(PinState(p)) %If pin is available
                        writeDigitalPin(app_Arduino,char("D"+num2str(ArdP(p))),0) %Turn off desired pogo-pin
                        if(app.PreBiasTime_1.Value ~= 0) %If there is a prebias
                            [I_temp t_temp TC_temp TC_time_temp] = RunBias(app, PreBias_1,PreBiasTime_1*60,(PreBiasTime*60)/2,th,Ih,PinState,ArdP,LampSet,LampColor,1,p) %Run prebias on pin % To change because rusBias will be modified. Only toggle the switch for a given amount of time .
                            app.TC = [TC_temp app.TC]; %Record temperature values
                            app.TC_time = [TC_time_temp app.TC_time]; %Record temperature time values
                            Ih = [Ih I_temp]; %Record current values
                            th = [th t_temp]; %Record current time values
                            hold(app.It_1,'on')
                        end
                        writeDigitalPin(app.Arduino,'D2',1); %Turn on relay switch to Impedance Analyzer (IA), enable connection
                        writeDigitalPin(app.Arduino,char("D"+num2str(ArdP(p))),1); %Turn on desired pogo-pin
                        LampSet(p) = LampColor(p); %Turn on pin lamp
                        [Ctemp, Rtemp] = RunProgCV(app, Progam) %Run CV measurement
                        app.P(p).C = [app.P(p).C Ctemp]; %Save capacitance data in pin struct
                        app.P(p).R = [app.P(p).R Rtemp]; %Save resistance data in pin struct
                        app.P(p).V = [app.P(p).V  V']; %Save voltage data in pin struct
                        PlotCV(app,app.P(p).C,app.P(p).V,CVPlots(p)); %Plot CV curve
                    end
                    
                    % here add the isstresscompleted function to turn off (or on?) the other hotplates if needed.
                    % After each pin has been measured, check if all hotplates have been held at the target temperature for the set step duration. If one of them has, turn them off and let them wait.
                    % Also check if the temperature has reached room temperature
                    for iHP=1:3
                        [stress_status]=isstresscompleted(app,iHP,HPdata); % is any hotplate ready to stop stressing?
                        
                        % Is any hotplate ready to stop temperature rampup?
                        %%% code here
                        
                        % Is any hotplate ready to stop temperature cool down (ie turn off the fan)?
                        
                        
                    end
                end
                writeDigitalPin(app.Arduino,char("D"+num2str(ArdP(p))),0) %Turn off desired pogo-pin after it has been measured
            end
            
            FlatbandFitting(app,PinState,PlotCVby2); %Fit flatband for pin
        end
        
        function [stress_status] = isstresscompleted(app,MUnb) % HP is the tab/hotplate number
            switch HPnb % Replace this by non-scalar structures % this  part will be removed and all the paramters will be saved initially in StartProc in the MD and HW structures
                case 1
                    setbiastime=app.dt_1;
                    Arduino_HP=app.Arduino.HP1;
                    ArdP=ArdP_HP1;
                    setCoolT=app.TempC_1;
                    PinState_HP=PinState.HP1;
                case 2
                    setbiastime=app.dt_2;
                    Arduino_HP=app.Arduino_HP2;
                    ArdP=ArdP_HP2;
                    setCoolT=app.TempC_2;
                    PinState_HP=PinState.HP2;
                case 3
                    setbiastime=app.dt_3;
                    Arduino_HP=app.Arduino_HP3;
                    ArdP=ArdP_HP3;
                    setCoolT=app.TempC_3;
                    PinState_HP=PinState.HP3;
            end
            
            % Parameters
            Arduino=HW(MUbn).Arduino;
            setbiastime=MD(Munb).MDdata.setbiastime;
            % etc
            %%% add other parameters here
            
            
            % test whether a hotplate has been stressed for the duration of the time increment
            time_inc=toc-HPdata.startbiastime; % HPdata should be defined in startproc.
            if(time_inc>=setbiastime) % Need to also include a condition to avoid turning on the fan of the hotplate currently measuring
                stress_status(HPnb)=1;
                writeDigitalPin(app.Arduino_HP,'D11',1); %Turn on Fan (verify pin number). ATTENTION: note that the fan has to be turned off once the target cooled temperature is obtained! Erick will create a function checking temperature and we can call it in fcncallback
                % Stop hotplate
                setHPTemp(app, app.HP,setCoolT); % verify the name of HP1 cooling temperature. Need to define setCoolT_HP1 in the main.
                setHPTemp(app, app.HP,setCoolT);
                % stop bias (8 POGO pins)
                for p = 1:length(PinState_HP) %Parse through all pins of one hotplate
                    writeDigitalPin(Arduino_HP,char("D"+num2str(ArdP(p))),0); % Set all available pins to 0 or off
                end
            else
                stress_status(HPnb)=0;
            end
        end
        
        % Function to call at regular time intervals in the infinite loop to check the status of each hotplate measurement
        function [meas_flag,HPdata] = fcncallback(app,MUnb) % MUnb is the number of the measurement unit considered (1,2 or 3)
            % Find hotplate parameters
            switch MUnb % this  part will be removed and all the paramters will be saved initially in StartProc in the MD and HW structures
                case 1
                    setbiastime=app.dt_1;
                    Arduino=app.Arduino.HP1;
                    ArdP_HP=ArdP.HP1;
                    setCoolT=app.TempC_1;
                    HP_data=HPdata.HP1;
                    PinState_HP=PinState.HP1;
                case 2
                    setbiastime=app.dt_2;
                    Arduino=app.Arduino.HP2;
                    ArdP_HP=ArdP.HP2;
                    setCoolT=app.TempC_2;
                    HP_data=HPdata.HP2;
                    PinState_HP=PinState.HP2;
                case 3
                    setbiastime=app.dt_3;
                    Arduino=app.Arduino.HP3;
                    ArdP_HP=ArdP.HP3;
                    setCoolT=app.TempC_3;
                    HP_data=HPdata.HP3;
                    PinState_HP=PinState.HP3;
            end
            
            % Parameters
            Arduino=HW(MUbn).Arduino;
            setbiastime=MD(Munb).MDdata.setbiastime;
            % etc
            %%% add other parameters here
            
            % Check if biasing needs to be started
            if(getTC(app,nHP)<=TempTresh+Err && getTc(app,nHP)>=TempTresh-Err && meas_flag(nHP)==1) % If the measurement temperature is reached and the measurement flag is 1 (measurement already performed)
                WriteDigitalPin(HW().Arduino,'A0',0); % Normally closed position, Keithley connected
                %%%%%%%%%%%% Turn all pins on based on pinstate table
                HPdata.startbiastime=toc;
                HPdata.meas_flag=0; % Turn flag to 0 after the bias was started
                % Record temperature and time?
            end
            
            % Check if stress has been completed to stop bias and start ramping down temperature
            %%%%% code here (from isstresscompleted function)
            time_inc=toc-HP_data.startbiastime;
            if(time_inc>=setbiastime && getTc(app,nHP)==StressT) % Temperature condition to avoid turning on the fan if ramping has already started or if room T has been reached.
                % Turn off bias by turning off each POGO pin
                for p = 1:length(PinState_HP) %Parse through all  pins
                    writeDigitalPin(Arduino,char("D"+num2str(ArdP_HP(p))),0); %Set all available pins to 0 or off
                end
                % ramp down temperature by setting to CoolT
                % Turn on fan
            end
            
            % Check if the fan can be turned off
            %% code here
            % conditions: steady-state bias is over and cooling temperature has been reached
            
            % Check if a measurement can be started
            [stress_status] = isstresscompleted(iHP,HPdata); % Can remove the isstresscompleted function and just add the condition "time_inc=toc-HPdata.startbiastime; if(time_inc>=setbiastime)" here % HPdata should be defined in startproc.
            if(stress_status && getTC(app,iHP)<=TempTresh+Err && getTc(app,iHP)>=TempTresh-Err && meas_flag==0)
                % turn off fan, pause 10 s
                %%%% code here
                % Run the CV measurement
                %%%% code here
                % Start ramping up HP temperature after measurement and set meas_flag to 1 (cut and copy code from RunIterCV here)
                % After the measurement, all pins of the hotplate should be off. Then toggle relay to Keithley (to allow LCR measurements of another hotplate)
                writeDigitalPin(Arduino,'A0',0) % Turn off the toggle switch board, ie connect to the Keithley
                % Start ramping temperature of the hotplate to the stress temperature
                setHPTemp(app,app_HP,setStressT) %Turn off desired hotplate
                setHPTemp(app,app_HP,setStressT) %Turn on heating & set to stress temperature of desired hotplate
                meas_flag(HPnb)=1; % Set flag to 1 after measurement completed
            end
        % End function
        end


        function FlatbandFitting(app,PinState,PlotCVby2)
            VfbTimePlotColors = ["bo-","yo-","go-","mo-"];
            IterM_1 = app.IterM_1.Value; %Set # of measurements per interation
            for p=1:length(PinState) %For all pins
                if(PinState(p)) %If the pin is available
                    if (app.DerPeaks_1.Value) %If fitting type set to derivative peaks
                        m = size(app.P(p).C,2)/IterM_1; % Set # of total interations so far
                        VFBfitNDeriv(app,p,m,IterM_1,PlotCVby2(p)) %Fit for flatband (data automatically updated within function)
                    else %If fitting type is linear 1/C^2
                        [Cfit, Vfit,Vfbtemp] = VFBfitN(app, C,Vall,IterM_1,PlotCVby2(p)) %Fit for flatband
                        app.P(p).Vfb = mean(Vfbtemp'); %Update flatband data
                        app.P(p).VfbStd = std(Vfbtemp');
                        
                        m = size(app.P(p).C,2)/IterM_1; % Set # of total interations so far
                        t_off = tInSec(app, string(app.t_offset_unit_1.Value), app.TimeOffset_1.Value); % Definte time-Offset in seconds
                        app.P(p).tfb = 0:app.dtime:((m-1)*app.dtime); % Set flatband time array
                        app.P(p).tfb = app.P(p).tfb+t_off; % Add time-offset
                    end
                    
                    
                    if (app.dt_1.Value == 0) %If no time increment (repeating CV)
                        m = size(app.P(p).C,2)/IterM_1;
                        app.P(p).tfb = (0:m); %Set x-axis ("time") increment for # of measurements
                        plot(app.VFBtime_1,app.P(p).tfb,app.P(p).VfbAve,VfbTimePlotColors(p),'LineWidth',2)
                        app.VFBtime_1.XLabel.String = "# of Sweeps"; %Change flatband voltage x-axis title
                        app.VFBtime_1.Title.String = "Flatband Voltage Vs. Number of Sweeps";
                    else
                        plot(app.VFBtime_1,app.P(p).tfb/3600,app.P(p).VfbAve,char(VfbTimePlotColors(p)),'LineWidth',2,'MarkerFaceColor',[1,1,1]) %Plot Average Vfb vs. time
                        app.VFBtime_1.XLabel.String = "Time (hr)";
                        app.VFBtime_1.Title.String = "Flatband Voltage Vs. Time";
                        hold(app.VFBtime_1,'on')
                    end
                end
            end
            hold(app.VFBtime_1,'off')
        end
        
        function VFBfitNDeriv(app,p,m,IterM_1,PlotCVby2_p)
            Cs = app.P(p).C; % C in struct for pin p
            Vs = app.P(p).V; % V in struct for pin p
            Vi=Vs(1):0.001:Vs(end); %Interpolated Voltage Bias Range
            t_off = tInSec(app, string(app.t_offset_unit_1.Value), app.TimeOffset_1.Value); % Definte time-Offset in seconds
            app.P(p).tfb = 0:app.dtime:((m-1)*app.dtimema); % Set flatband time array
            app.P(p).tfb = app.P(p).tfb+t_off; % Add time-offset
            set(PlotCVby2_p, 'ColorOrder', jet((app.Iter_tot_1.Value+1)))
            app.P(p).Vfb = []; app.P(p).Vfit = []; app.P(p).Cfit = [];
            app.P(p).VfbAve = []; app.P(p).VfbStd = [];
            hold(PlotCVby2_p, 'off')
            plot(PlotCVby2_p,[],[],'LineWidth',2)
            hold(PlotCVby2_p, 'on')
            for j = 1:m*IterM_1 % Parse through all CV data for pin
                Ci=interp1(Vs(:,j),Cs(:,j),Vi,'spline'); % Interpolate CV data
                Cby2 = 1./(Ci./max(Ci)).^2; %Define normalized 1/C^2
                %d1Cby1 = (diff(Cby2,1)./diff(Vi,1));
                Vicut = Vi(Cby2<10); %Concatenate 1/C^2 to remove noisy data (Voltage)
                Cby2cut = Cby2(Cby2<10); %Concatenate 1/C^2 to remove noisy data (Capacitance)
                d1Cby1 = ((diff(Cby2cut,1))./diff(Vicut,1)); %Take derivative of non-noisy interpolated CV data
                
                [mind1Cby1 minInd1] = min(d1Cby1); %Find minumum point of 1/C^2 derivative
                d2Cby2 = (diff(d1Cby1)./diff(Vicut(1:end-1))); %Take second derivative of 1/C^2
                
                VExtract = Vicut(minInd1+2:end); %Concatenate voltage to data above 1st derivative minimum (removes noise data further 2nd derivative data)
                Cby2Extract = d2Cby2(minInd1:end); %Concatenate capacitance to data above 1st derivative minimum (removes noise data further 2nd derivative data)
                
                app.P(p).Vfit = [app.P(p).Vfit VExtract]; %Save non-noisy 2nd derivative voltage array
                app.P(p).Cfit = [app.P(p).Cfit Cby2Extract]; %Save non-noisy 2nd capacitance voltage array
                
                [maxd2Cby2 maxInd2] = max(Cby2Extract); %Find max peak position or index
                app.P(p).Vfb = [app.P(p).Vfb VExtract(maxInd2)]; %Define flatband as voltage as that peak
                Vfb = app.P(p).Vfb; %Save Vfb variable
                
                plot(PlotCVby2_p,Vicut(minInd1:end-2),d2Cby2(minInd1:end),'LineWidth',2); %Plot non-noisy 2nd derivative 1/C^2 data
                plot(PlotCVby2_p,Vfb(end),maxd2Cby2,'k*','LineWidth',1); %Show fitted flatband peak in plot
                ylim([-inf,0]) %Assuming Vfb is below zero, limit yscale for plot from -infinity to 0
            end
            hold(PlotCVby2_p, 'off')
            
            %Since many CV measurements may be taken for time point,
            %the next lines average Vfb for the multiple measurements at each time point
            for j=1:m %For all measurements
                ind = j*IterM_1; %For every set of repeated measurements
                app.P(p).VfbAve = [app.P(p).VfbAve mean(Vfb(ind:-1:ind-IterM_1+1)')]; %Average and add average to defined average flatband array
                app.P(p).VfbStd = [app.P(p).VfbStd std(Vfb(ind:-1:ind-IterM_1+1)')]; %Calculate standard deviation (STD) and add values to defined flatband STD array
            end
        end % End of function VFBfitNDeriv
        
    end % End of the public methods?
    
    methods (Access = private)
        
        
        function results = timeLoop(app)
            %%% Do something here
            %% I have done something
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset %Reset all intruments
            app.HW = InitHardware(app); %initialize arduino and hotplate connections
        end

        % Button pushed function: Export_CV_1
        function Export_CV_1Pushed(app, event)
            clf; shg;
            try
                PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value];
                CVPlots = [app.CV1_1,app.CV2_1,app.CV3_1,app.CV4_1];
                ValidCVPlots = CVPlots(PinState==1);
                for e1 = 1:length(CVPlots)
                    if (PinState(e1))
                        subplot(2,2,e1)
                        hold on
                        set(gca, 'ColorOrder', jet(app.Iter_tot_1.Value+1),'FontSize',14)
                        colormap('jet');
                        IterM_1 = app.IterM_1.Value;
                        map = fliplr(hot(length(CVPlots(e1).Children)/IterM_1+2));
                        t_off = tInSec(app, string(app.t_offset_unit_1.Value), app.TimeOffset_1.Value);
                        TimeArray = t_off+(0:app.Iter_tot_1.Value).*app.dt_1.Value
                        if (app.dt_1.Value == 0)
                            lgn = "Iter: " + (0:app.Iter_tot_1.Value);
                        else
                            lgn = (TimeArray) + " " + string(app.t_inc_unit_1.Value);
                        end
                        h= [];
                        for e2 =  (length(CVPlots(e1).Children)/IterM_1):-1:1
                            x = [];
                            y = [];
                            ind = e2*IterM_1;
                            for e3 = 0:1:IterM_1-1
                                x = [(CVPlots(e1).Children(ind-e3).XData)' x]
                                y = [(CVPlots(e1).Children(ind-e3).YData)' y]
                            end
                            htemp = plot(x,y,'LineWidth', 2,'Color',map(end-e2,:))
                            h = [h, htemp(1)];
                        end
                        legend(h,lgn)
                        title("Pin "+e1);
                        xlabel(CVPlots(e1).XLabel.String);
                        ylabel(CVPlots(e1).YLabel.String);
                        hold off
                    end
                end
            end
        end

        % Button pushed function: Export_byC2_1
        function Export_byC2_1Pushed(app, event)
            clf; shg;
            try
                PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value];
                byC2Plots = [app.byC2_1_1,app.byC2_2_1,app.byC2_3_1,app.byC2_4_1];
                ValidbyC2Plots = byC2Plots(PinState==1);
                
                for e1 = 1:length(byC2Plots)
                    if (PinState(e1))
                        subplot(2,2,e1)
                        hold on
                        set(gca, 'ColorOrder', fliplr(hot(ceil(length(app.byC2_1_1.Children)./2))))
                        IterM_1 = app.IterM_1.Value;
                        
                        for e2 = ((length(byC2Plots(e1).Children)-1)/IterM_1)-1:-2:1
                            plot(byC2Plots(e1).Children(e2).,byC2Plots(e1).Children(e2).YData,'-','LineWidth',2)
                        end
                        for e2 = ((length(byC2Plots(e1).Children)-1)/IterM_1):-2:1
                            plot(byC2Plots(e1).Children(e2).,byC2Plots(e1).Children(e2).YData,'k*','LineWidth',2)
                        end
                        
                        title("Pin "+e1);
                        xlabel(byC2Plots(e1).XLabel.String);
                        ylabel(byC2Plots(e1).YLabel.String);
                        hold off
                    end
                end
            end
        end

        % Button pushed function: ExportVFBtime_1
        function ExportVFBtime_1Pushed(app, event)
            clf; shg;
            try
                VfbTimePlotColors = ["bo-","yo-","go-","mo-"];
                PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value];
                StdOff = 0;
                for e = 1:length(PinState)
                    if(~PinState(e))
                        StdOff = StdOff + 1;
                    else
                        break;
                    end
                end
                ValidColors = fliplr(VfbTimePlotColors(PinState==1))
                for e = length(app.VFBtime_1.Children):-1:1
                    errorbar(app.VFBtime_1.Children(e).,app.VFBtime_1.Children(e).YData,app.P(e+StdOff).VfbStd,ValidColors(e),'LineWidth', 2)
                    title(app.VFBtime_1.Title.String);
                    xlabel(app.VFBtime_1.XLabel.String);
                    ylabel(app.VFBtime_1.YLabel.String);
                    hold on
                end
                hold off
            end
        end

        % Button pushed function: OpenFileLocationButton
        function OpenFileLocationButtonPushed(app, event)
            app.dir = uigetdir('C:\');
            try
                app.FileLoc.Value = app.dir;
                cd(app.dir);
            end
        end

        % Button pushed function: StartProcessButton
        function StartProc(app, event)
            %Turns off holds for/reset all plots in app
            hold(app.It_1,'off'); hold(app.VFBtime_1,'off'); hold(app.TempTime_1,'off')
            hold(app.CV1_1,'off'); hold(app.CV2_1,'off'); hold(app.CV3_1,'off'); hold(app.CV4_1,'off');
            hold(app.byC2_1_1,'off'); hold(app.byC2_2_1,'off'); hold(app.byC2_3_1,'off'); hold(app.byC2_4_1,'off');
            
            %Disable control of turning on/off pins
            app.P1_1.Enable = 'off'; app.P2_1.Enable = 'off'; app.P3_1.Enable = 'off'; app.P4_1.Enable = 'off';
            
            %Set pin lamps to gray
            app.P1Lamp_1.Color = [.9, .9, .9]; app.P2Lamp_1.Color = [.9, .9, .9]; app.P3Lamp_1.Color = [.9, .9, .9]; app.P4Lamp_1.Color = [.9, .9, .9];
            
            %% CV Program Definition for Impedance Anlayzer
            if (app.Sweep_1.Value == "Down")
                SWD = "SWD2"; %Set program parameter to sweep down
            else
                SWD = "SWD1"; %Set program parameter to sweep up
            end
            
            %Depending on itegration time specified in app input, set
            %corresponding parameter in the program script
            if (app.ITM_1.Value == "500 us")
                ITM_1 = "ITM1";
            elseif (app.ITM_1.Value == "5 ms")
                ITM_1 = "ITM2";
            elseif (app.ITM_1.Value == "100 ms")
                ITM_1 = "ITM3";
            end
            
            %Set frequency multiplaction factor based on defined unit in app dropdown
            if (app.Freq_unit_1.Value == "KHz")
                FreqU = 1e3;
            elseif (app.Freq_unit_1.Value == "MHz")
                FreqU = 1e6;
            end
            
            %Define CV program to be read in by Impedance Analyzer
            Prog_CV = "PROG"...
                + "'10 FNC1'," ... %Impedance Measurement
                + "'20 SWM2'," ... % Single Mode Sweep
                + "'30 IMP5'," ... % Cs-Rs Circuit
                + "'40 SWP2'," ... % DC Bias Sweep
                + "'50 "+SWD+"'," ... % Sweep Direction
                + "'60 "+ITM_1+"'," ... % Integration Time
                + "'70 NOA_1="+app.NOA_1.Value+"'," ... % # of Averages
                + "'80 OSC_1="+app.OSC_1.Value*1e-3+";FREQ="+app.Frequency_1.Value*FreqU+"'," ... % AC Amplitude (V) & Frequency
                + "'90 START="+app.VNeg_1.Value+";STOP="+app.VPos_1.Value+"'," ... %Starting Negative & Positive Volgates
                + "'100 STEP="+app.VStep_1.Value*1e-3+"'," ... %DC Sweep Step Magnitude (V)
                + "'110 DTIME=0'," ... % Measurement Delay Time Set to 0 (No Delay)
                + "'120 SHT1'," ... % Short Calibration Set to On
                + "'130 OPN1'," ... % Open Calibration Set to On
                + "'140 AUTO'," ... % Auto-Scale A & B
                + "'150 CPYM2'," ... % Copy Data Mode 2
                + "'160 SWTRG'," ... % Single Trigger Run
                + "'170 COPY'," ... % Copy Data to Instrument
                + "'180 DCOFF'," ... % Doesn't work, but attempt to turn off DC bias
                + "'190 END'";
            
            
            %% Initialize Variables
            app.dtime = tInSec(app, string(app.t_inc_unit_1.Value), app.dt_1.Value); %Set measurement increment time in sec
            V = app.VNeg_1.Value:app.VStep_1.Value*1e-3:app.VPos_1.Value; %Create CV voltage sweep array
            LampSet = [app.P1Lamp_1.Color, app.P2Lamp_1.Color, app.P3Lamp_1.Color, app.P4Lamp_1.Color]; %Define an array of lamp color objects
            LampColor = ["blue","yellow","green","white"]; %Define an array of lamp colors
            % Hotplate 1
            MU(1).PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value, app.P5_1.Value, app.P6_1.Value, app.P7_1.Value, app.P8_1.Value]; %Define array of pin value objects (objects that indicate on or off state)
            MU(1).ArdP = [app.AP1_1.Value, app.AP2_1.Value, app.AP3_1.Value, app.AP4_1.Value, app.AP5_1.Value, app.AP6_1.Value, app.AP7_1.Value, app.AP8_1.Value]; %Define array of arduino pin value objects (objects that indicate arduino pin for pogo-pin)
            % Hotplate 2
            MU(2).PinState = [app.P1_2.Value, app.P2_2.Value, app.P3_2.Value, app.P4_2.Value, app.P5_2.Value, app.P6_2.Value, app.P7_2.Value, app.P8_2.Value]; %Define array of pin value objects (objects that indicate on or off state)
            MU(2).ArdP = [app.AP1_2.Value, app.AP2_2.Value, app.AP3_2.Value, app.AP4_2.Value, app.AP5_2.Value, app.AP6_2.Value, app.AP7_2.Value, app.AP8_2.Value]; %Define array of arduino pin value objects (objects that indicate arduino pin for pogo-pin)
            % Hotplate 3
            MU(3).PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value, app.P5_1.Value, app.P6_1.Value, app.P7_1.Value, app.P8_1.Value]; %Define array of pin value objects (objects that indicate on or off state)
            MU(3).ArdP = [app.AP1_1.Value, app.AP2_1.Value, app.AP3_1.Value, app.AP4_1.Value, app.AP5_1.Value, app.AP6_1.Value, app.AP7_1.Value, app.AP8_1.Value]; %Define array of arduino pin value objects (objects that indicate arduino pin for pogo-pin)
            CVPlots = [app.CV1_1,app.CV2_1,app.CV3_1,app.CV4_1];
            PlotCVby2=[app.byC2_1_1,app.byC2_2_1,app.byC2_3_1,app.byC2_4_1]; %Define array of plot objects for CV & 1/C^2 vs. V curves
            
            for i=1:length(PinState) %For all pins, set parameters to null
                set(CVPlots(i), 'ColorOrder', jet((app.Iter_tot_1.Value+1)*app.IterM_1.Value)); %Define color order for plotting based on % of iterations
                app.P(i).C = []; app.P(i).R=[]; app.P(i).Vall = []; app.P(i).th=[]; app.P(i).Ih=[]; %Null C,R,C,Ih,th (current/time history)
                app.P(i).V = []; app.P(i).Vfit = []; app.P(i).Cfit = []; app.P(i).Vfb = []; app.P(i).tfb = []; %Null V,Vfit,Cfit,Vfb,tfb (flatband voltage/time)
                app.P(i).VfbAve = []; app.P(i).VfbStd = []; %Null average & standard deviation Vfb
            end
            
            %% Save Parameters to Text File:
            fileDest = app.FileLoc.Value;
            fileID = fopen(fileDest+"\"+app.DataFileName.Value+".txt",'wt');
            
            fprintf(fileID,"Parameter File:\n\nSweep Parameters: \n");
            fprintf(fileID,"Frequency_1: "+app.Frequency_1.Value+" "+app.Freq_unit_1.Value+"      VAC: "+app.OSC_1.Value+" mV"+"\n");
            fprintf(fileID,"Negative Voltage: "+app.VNeg_1.Value+" V       Positive Voltage: "+app.VPos_1.Value+" V \n");
            fprintf(fileID,"Voltage Step: "+app.VStep_1.Value+" mV         Averages per Point: "+app.NOA_1.Value+"\n");
            fprintf(fileID,"Integration Time: "+app.ITM_1.Value+"         Sweep_1 Direction: "+app.Sweep_1.Value+"\n");
            
            fprintf(fileID,"\nExperiment Parameters: \n");
            fprintf(fileID,"Time Increment: "+app.dt_1.Value+" "+app.t_inc_unit_1.Value+"       Time Offset: "+app.TimeOffset_1.Value+" "+app.t_offset_unit_1.Value+"\n");
            fprintf(fileID,"Total # Iterations: "+app.Iter_tot_1.Value + "       # Iterations/Measure: "+app.IterM_1.Value + "\n");
            fprintf(fileID,"Pre-CV Bias_1: "+app.PreBias_1.Value + "V       Pre-CV Bias Time: "+app.PreBiasTime_1.Value + " min \n");
            
            fprintf(fileID,"Temperature: "+app.TempH_1.Value+" C       Stress Voltage: "+app.Bias_1.Value+" V \n");
            fprintf(fileID,"Nitride Thickness: "+app.THK_1.Value+" nm\n");
            
            fclose(fileID);
            
            %% Run & Get Data:
            StressT = app.TempH_1.Value; %Set Desired Stress Temperature (C)
            CoolT = app.TempC_1.Value; %Set Desired Cool Temperaure (C) to Take CV at
            
            setStressT = CorrectT(app,StressT); %Set Hotplate Set Sress Temp Based on Hotplate Thermocouple Calibration (C)
            setCoolT = CorrectT(app,CoolT); %%Set Hotplate Set Cool Temp Based on Hotplate Thermocouple Calibration (C)
            ErrC = 1; %Set Cool Temperature +/- Error in Degree (C) (e.x. app will excute function to within +/- 1 degree of set temp)
            ErrS = 1; %Set Stress Temperature +/- Error in Degree (C) (e.x. app will excute function to within +/- 1 degree of set temp)
            WtC = app.CoolTime_1.Value; %Temperature Cool Wait Time (s)
            WtS = app.HeatTime_1.Value; %Temperature Heat Wait Time (s)
            
            setHPTemp(app,app.HP,setCoolT) %Excute Function to Set Hotplate Temperaure
            %Inputs: Hotplate Visa Object, Desired Set Temperature
            
            if(getTC(app)>CoolT) %If Thermocouple temperature is greater than the cool/measurement temperature
                writeDigitalPin(app.Arduino,'D3',1) %Turn on Fan
            else
                writeDigitalPin(app.Arduino,'D3',0) %Turn off Fan
            end
            
            tic; %Start recording time during measurement
            [app.TC app.TC_time] = WaitPlotTemp(app,CoolT,ErrC,10); %Wait until temperature reaches cool/measurement temp
            
            hold(app.TempTime_1,'on')
            
            writeDigitalPin(app.Arduino,'D3',0) %Turn off Fan
            pause(10)
            
            PreBias_1 = app.PreBias_1.Value; PreBiasTime_1 = app.PreBiasTime_1.Value; IterM_1 = app.IterM_1.Value; %Define shorter param names
            th=[];Ih=[];
            
            %% Initial CV & Flatband Fitting
            [th Ih] = RunIterCV(app,V,Prog_CV,PreBias_1,PreBiasTime_1,th,Ih,PinState,ArdP,LampSet,LampColor,CVPlots,PlotCVby2) %Run iterative CV measurement and record time & current
            hold(app.It_1,'on')
            
            
            %% Note for setting temperatures:
            % If heating light is on for the hotplate, first command sent to hotplate, turns off heating
            % Therefore, 2 commands are needed to set a new temperature,
            %the first to turn off HP heat, the second to turn back on at the correct new set temperature
            
            %% Running Process
            for i = 1:app.Iter_tot_1.Value
                writeDigitalPin(app.Arduino,'D2',0)%Turn CV Off
                
                setHPTemp(app,app.HP,setStressT) %Turn off hotplate heating
                setHPTemp(app,app.HP,setStressT) %Turn on heating & set to stress temperature
                
                [TC_temp TC_time_temp] = WaitPlotTemp(app,StressT,ErrS,WtS); %Wait until temperature reaches stress temp
                app.TC = [TC_temp app.TC]; %Record temperature values
                app.TC_time = [TC_time_temp app.TC_time]; %Record temperature time values
                
                %Stress
                if (app.dt_1.Value ~= 0) %If time increment
                    t_Istep = ceil(app.dtime/app.Itpts_1.Value); % Incremental time to get set current-time points
                    [I_temp t_temp TC_temp TC_time_temp] = RunBias(app, app.Bias_1.Value,app.dtime,t_Istep,th,Ih,PinState,ArdP,LampSet,LampColor,0,5) %Trigger Measurement, Request Data
                    
                    app.TC = [TC_temp app.TC]; %Record temperature values
                    app.TC_time = [TC_time_temp app.TC_time]; %Record temperature time values
                    Ih = [Ih I_temp]; %Record current history
                    th = [th t_temp]; %Record current time history
                end
                
                writeDigitalPin(app.Arduino,'D3',1); %Turn on Fan
                
                setHPTemp(app,app.HP,setCoolT); %Turn off heating
                setHPTemp(app,app.HP,setCoolT); %Turn on heating & set to cooling/measurement temperature
                
                [TC_temp TC_time_temp] = WaitPlotTemp(app,CoolT,ErrC,WtC); %Wait until temperature reaches cool temp
                app.TC = [TC_temp app.TC]; %Record temperature values
                app.TC_time = [TC_time_temp app.TC_time]; %Record temperature time values
                
                writeDigitalPin(app.Arduino,'D3',0) %Turn off Fan
                pause(10) %Wait 10 seconds for temperature to equilibrate
                [th Ih] = RunIterCV(app,V,Prog_CV,PreBias_1,PreBiasTime_1,th,Ih,PinState,ArdP,LampSet,LampColor,CVPlots,PlotCVby2) %Take iterative CV measurement
                
                TC = app.TC; TC_time = app.TC_time; %Set new var for temperature and time arrays
                app.I = Ih; app.It_t = th; %Save current & time to app structure
                Data = app.P; %Set new variable for all pin data
                save(fileDest+"\"+app.DataFileName.Value+".mat","Data","TC","TC_time","Ih","th"); %Save all data
            end
            %After completed meaurement
            setHPTemp(app,app.HP,25) %Turn off hotplate heating (sets hotplate display to 25C)
            msgbox('Process Complete');
            save(fileDest+"\"+app.DataFileName.Value+".mat"); %Save all workspace data
        end

        % Button pushed function: StopProcessButton
        function StopProc(app, event)
            
        end

        % Button pushed function: UpdateFit_1
        function UpdateFit_1ButtonPushed(app, event)
            fitfail = 0;
            PinState = [app.P1_1.Value, app.P2_1.Value, app.P3_1.Value, app.P4_1.Value]; %Define array of pin value objects (objects that indicate on or off state)
            PlotCVby2 = [app.byC2_1_1,app.byC2_2_1,app.byC2_3_1,app.byC2_4_1]; %Define array of plot objects for 1/C^2 vs. V curves
            VfbTimePlotColors = ["bo-","yo-","go-","mo-"];

            for p = 1:length(PinState)
                if(PinState(p))
                    [Cfit,Vfit,Vfbtemp,fitfail] = VFBfitN(app,app.P(p).C,app.P(p).V,length(PlotCVby2.Children),PlotCVby2(p));
                    app.P(p).Vfb = [];
                    app.P(p).VfbStd = [];
                    iterM = app.IterM_1.Value;
                    
                    for i = (length(Vfbtemp)/iterM):-1:1
                        ind = i*iterM;
                        app.P(p).Vfb = [app.P(p).Vfb mean(Vfbtemp(ind:-1:ind-iterM+1)')]
                        app.P(p).VfbStd = [app.P(p).VfbStd std(Vfbtemp(ind:-1:ind-iterM+1)')]
                    end
                    
                    if(fitfail)
                        msgbox("Fit Failed :(");
                        return;
                    end
                    
                    if (app.dt_1.Value == 0)
                        tfb = (0:(length(app.Vfb)-1))
                        plot(app.VFBtime_1,0:(length(app.Vfb)-1),app.Vfb,VfbTimePlotColors(p),'LineWidth',2)
                        app.VFBtime_1.XLabel.String = "# of Sweeps";
                        app.VFBtime_1.Title.String = "Flatband Voltage Vs. Number of Sweeps";
                    else
                        m = length(app.CV1_1.Children)/app.IterM_1.Value
                        tfb = 0:app.dtime:app.dtime*(length(app.Vfb)-1)
                        plot(app.VFBtime_1,tfb/3600,app.Vfb,VfbTimePlotColors(p),'LineWidth',2)
                        app.VFBtime_1.XLabel.String = "Time (hr)";
                        app.VFBtime_1.Title.String = "Flatband Voltage Vs. Time";
                    end
                end
            end
         
            fileDest = app.FileLoc.Value
           
            TC = app.TC; TC_time = app.TC_time;
            app.I = Ih; app.It_t = th;
            Data = app.P;
            save(fileDest+"\"+app.DataFileName.Value+".mat","Data","TC","TC_time","Ih","th");
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                clear app.Arduino
                CloseHP(app, app.HP)
            end
            delete(app)
        end

        % Button pushed function: Export_It_1
        function Export_It_1Button(app, event)
            clf; shg;
            try
                hold on
                for i =  length(app.It_1.Children):-1:1
                    plot(app.It_1.Children(i).,abs(app.It_1.Children(i).YData),'ko-','LineWidth',2)
                end
                hold off
                set(gca,'FontSize',14,'YScale','Log');
                title(app.It_1.Title.String);
                xlabel(app.It_1.XLabel.String);
                ylabel("|Current (A)|");
                hold off
            end
        end

        % Button pushed function: Export_TempTime_1
        function Export_TempTime_1Button(app, event)
            clf; shg;
            try
                hold on
                for i =  length(app.TempTime_1.Children):-1:1
                    plot(app.TempTime_1.Children(i).XData,abs(app.TempTime_1.Children(i).YData),'-o','LineWidth',2,'Color',[1,.4,0])
                end
                hold off
                set(gca,'FontSize',14);
                title(app.TempTime_1.Title.String);
                xlabel(app.TempTime_1.XLabel.String);
                ylabel(app.TempTime_1.YLabel.String);
                hold off
            end
        end

        % Value changed function: DerPeaks_1
        function DerPeaks_1Pressed(app, event)
            value = app.DerPeaks_1.Value;
            if (value)
                app.byC2_1_1.Title.String = "d^2(1/C^2)/d^2 Vs. Voltage";
                app.byC2_1_1.YLabel.String = "d^2(1/C^2)/d^2V";
            else
                app.byC2_1_1.Title.String = "1/(C/C_o)^2 Vs Voltage";
                app.byC2_1_1.YLabel.String = "1/(C/C_o)^2V";
            end
        end

        % Value changed function: P1_1
        function P1_1Change(app, event)
            value = app.P1_1.Value;
            if(value)
                app.P1_1.Text = "ON";
                app.P1Lamp_1.Color = "red";
            else
                app.P1_1.Text = "OFF";
                app.P1Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P2_1
        function P2_1Changed(app, event)
            value = app.P2_1.Value;
            if(value)
                app.P2_1.Text = "ON";
                app.P2Lamp_1.Color = [1,.41,.16];
            else
                app.P2_1.Text = "OFF";
                app.P2Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P3_1
        function P3_1Changed(app, event)
            value = app.P3_1.Value;
            if(value)
                app.P3_1.Text = "ON";
                app.P3Lamp_1.Color = [.93,.69,.13];
            else
                app.P3_1.Text = "OFF";
                app.P3Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P4_1
        function P4_1Changed(app, event)
            value = app.P4_1.Value;
            if(value)
                app.P4_1.Text = "ON";
                app.P4Lamp_1.Color = "yellow";
            else
                app.P4_1.Text = "OFF";
                app.P4Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Button pushed function: PinReset_1
        function PinReset_1ButtonPushed(app, event)
            app.P1_1.Enable = 'on'; app.P2_1.Enable = 'on'; app.P3_1.Enable = 'on'; app.P4_1.Enable = 'on';
            app.P5_1.Enable = 'on'; app.P6_1.Enable = 'on'; app.P7_1.Enable = 'on'; app.P8_1.Enable = 'on';
        end

        % Value changed function: P5_1
        function P5_1ValueChanged(app, event)
            value = app.P5_1.Value;
            if(value)
                app.P5_1.Text = "ON";
                app.P5Lamp_1.Color = [0,1,1];
            else
                app.P5_1.Text = "OFF";
                app.P5Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P6_1
        function P6_1ValueChanged(app, event)
            value = app.P6_1.Value;
            if(value)
                app.P6_1.Text = "ON";
                app.P6Lamp_1.Color = "blue";
            else
                app.P6_1.Text = "OFF";
                app.P6Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P7_1
        function P7_1ValueChanged(app, event)
            value = app.P7_1.Value;
            if(value)
                app.P7_1.Text = "ON";
                app.P7Lamp_1.Color = "magenta";
            else
                app.P7_1.Text = "OFF";
                app.P7Lamp_1.Color = [.9, .9, .9];
            end
        end

        % Value changed function: P8_1
        function P8_1ValueChanged(app, event)
            value = app.P8_1.Value;
            if(value)
                app.P8_1.Text = "ON";
                app.P8Lamp_1.Color = [0.49,0.18,0.56];
            else
                app.P8_1.Text = "OFF";
                app.P8Lamp_1.Color = [.9, .9, .9];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartProcessButton = uibutton(app.UIFigure, 'push');
            app.StartProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartProc, true);
            app.StartProcessButton.Text = 'Start Process';
            app.OpenFileLocationButton = uibutton(app.UIFigure, 'push');
            app.OpenFileLocationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileLocationButtonPushed, true);
            app.OpenFileLocationButton.Text = 'Open File Location';
            app.FileLoc = uitextarea(app.UIFigure);
            app.FileLoc.Editable = 'off';
            app.FileLoc.Value = {'C:\Users\schar\Google Drive\PhD Research\Experimental Data\4_25_18'};
            app.SaveFileLocationLabel = uilabel(app.UIFigure);
            app.SaveFileLocationLabel.Text = 'Save File Location';
            app.StopProcessButton = uibutton(app.UIFigure, 'push');
            app.StopProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StopProc, true);
            app.StopProcessButton.Text = 'Stop Process';
            app.HPGUI_Group = uitabgroup(app.UIFigure);
            app.HP1_Tab = uitab(app.HPGUI_Group);
            app.HP1_Tab.Title = 'Hotplate 1';
            app.HP1_Group = uitabgroup(app.HP1_Tab);
            app.Input = uitab(app.HP1_Group);
            app.Input.Title = 'Input';
            app.ImpedanceAnalyzerCVLabel_1 = uilabel(app.Input);
            app.ImpedanceAnalyzerCVLabel_1.Text = 'Impedance Analyzer (CV)';
            app.Freq_unit_1 = uidropdown(app.Input);
            app.Freq_unit_1.Items = {'KHz', 'MHz'};
            app.Freq_unit_1.Value = 'MHz';
            app.FrequencyLabel = uilabel(app.Input);
            app.FrequencyLabel.Text = 'Frequency';
            app.Frequency_1 = uieditfield(app.Input, 'numeric');
            app.Frequency_1.Value = 1;
            app.PositiveVoltageVLabel = uilabel(app.Input);
            app.PositiveVoltageVLabel.Text = 'Positive Voltage (V)';
            app.VPos_1 = uieditfield(app.Input, 'numeric');
            app.VPos_1.Value = 7;
            app.NegativeVoltageVLabel = uilabel(app.Input);
            app.NegativeVoltageVLabel.Text = 'Negative Voltage (V)';
            app.VNeg_1 = uieditfield(app.Input, 'numeric');
            app.VNeg_1.Value = -7;
            app.VACmVpkpkEditFieldLabel = uilabel(app.Input);
            app.VACmVpkpkEditFieldLabel.Text = 'VAC [mV(pk-pk)]';
            app.OSC_1 = uieditfield(app.Input, 'numeric');
            app.OSC_1.Value = 100;
            app.SweepSwitchLabel = uilabel(app.Input);
            app.SweepSwitchLabel.Text = 'Sweep';
            app.Sweep_1 = uiswitch(app.Input, 'rocker');
            app.Sweep_1.Items = {'Down', 'Up'};
            app.Sweep_1.Value = 'Down';
            app.StepVoltagemVLabel = uilabel(app.Input);
            app.StepVoltagemVLabel.Text = 'Step Voltage (mV)';
            app.VStep_1 = uieditfield(app.Input, 'numeric');
            app.VStep_1.Value = 100;
            app.ofAveragesDropDownLabel = uilabel(app.Input);
            app.ofAveragesDropDownLabel.Text = '# of Averages';
            app.NOA_1 = uidropdown(app.Input);
            app.NOA_1.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.NOA_1.Value = '2';
            app.IntegrationTimeDropDownLabel = uilabel(app.Input);
            app.IntegrationTimeDropDownLabel.Text = 'Integration Time';
            app.ITM_1 = uidropdown(app.Input);
            app.ITM_1.Items = {'500 us', '5 ms', '100 ms'};
            app.ITM_1.Value = '5 ms';
            app.ExperimentalFittingLabel_1 = uilabel(app.Input);
            app.ExperimentalFittingLabel_1.Text = 'Experimental/Fitting:';
            app.t_offset_unit_1 = uidropdown(app.Input);
            app.t_offset_unit_1.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_offset_unit_1.Value = 'sec';
            app.t_inc_unit_1 = uidropdown(app.Input);
            app.t_inc_unit_1.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_inc_unit_1.Value = 'sec';
            app.THKnmEditFieldLabel = uilabel(app.Input);
            app.THKnmEditFieldLabel.Text = 'THK [nm]';
            app.THK_1 = uieditfield(app.Input, 'numeric');
            app.THK_1.Value = 76.96;
            app.HeatTempCLabel = uilabel(app.Input);
            app.HeatTempCLabel.Text = 'Heat Temp [C]';
            app.TempH_1 = uieditfield(app.Input, 'numeric');
            app.TempH_1.Value = 26;
            app.StressVoltageVLabel = uilabel(app.Input);
            app.StressVoltageVLabel.Text = 'Stress Voltage [V]';
            app.Bias_1 = uieditfield(app.Input, 'numeric');
            app.TimeOffsetLabel = uilabel(app.Input);
            app.TimeOffsetLabel.Text = 'Time Offset';
            app.TimeOffset_1 = uieditfield(app.Input, 'numeric');
            app.TimeIncLabel = uilabel(app.Input);
            app.TimeIncLabel.Text = 'Time Inc';
            app.dt_1 = uieditfield(app.Input, 'numeric');
            app.dt_1.Value = 5;
            app.TotalIterationsLabel = uilabel(app.Input);
            app.TotalIterationsLabel.Text = '# Total Iterations';
            app.Iter_tot_1 = uieditfield(app.Input, 'numeric');
            app.Iter_tot_1.Value = 1;
            app.IterationsMeasureLabel = uilabel(app.Input);
            app.IterationsMeasureLabel.Text = '# Iterations/Measure';
            app.IterM_1 = uieditfield(app.Input, 'numeric');
            app.IterM_1.Value = 1;
            app.ItDataPointsStressLabel = uilabel(app.Input);
            app.ItDataPointsStressLabel.Text = '# I-t Data Points/Stress';
            app.Itpts_1 = uieditfield(app.Input, 'numeric');
            app.Itpts_1.Value = 5;
            app.PreStressVoltageVLabel = uilabel(app.Input);
            app.PreStressVoltageVLabel.Text = 'PreStress Voltage [V]';
            app.PreBias_1 = uieditfield(app.Input, 'numeric');
            app.PreStressTimeminLabel = uilabel(app.Input);
            app.PreStressTimeminLabel.Text = 'PreStress Time [min]';
            app.PreBiasTime_1 = uieditfield(app.Input, 'numeric');
            app.CoolTempCLabel = uilabel(app.Input);
            app.CoolTempCLabel.Text = 'Cool Temp [C]';
            app.TempC_1 = uieditfield(app.Input, 'numeric');
            app.TempC_1.Value = 26;
            app.HeatWaitTimesLabel = uilabel(app.Input);
            app.HeatWaitTimesLabel.Text = 'Heat Wait Time [s]';
            app.HeatTime_1 = uieditfield(app.Input, 'numeric');
            app.HeatTime_1.Value = 5;
            app.CoolWaitTimesLabel = uilabel(app.Input);
            app.CoolWaitTimesLabel.Text = 'Cool Wait Time [s]';
            app.CoolTime_1 = uieditfield(app.Input, 'numeric');
            app.CoolTime_1.Value = 5;
            app.P1Lamp_1 = uilamp(app.Input);
            app.P2Lamp_1 = uilamp(app.Input);
            app.P3Lamp_1 = uilamp(app.Input);
            app.P4Lamp_1 = uilamp(app.Input);
            app.P1_1 = uibutton(app.Input, 'state');
            app.P1_1.ValueChangedFcn = createCallbackFcn(app, @P1_1Change, true);
            app.P1_1.Text = 'OFF';
            app.P2_1 = uibutton(app.Input, 'state');
            app.P2_1.ValueChangedFcn = createCallbackFcn(app, @P2_1Changed, true);
            app.P2_1.Text = 'OFF';
            app.P3_1 = uibutton(app.Input, 'state');
            app.P3_1.ValueChangedFcn = createCallbackFcn(app, @P3_1Changed, true);
            app.P3_1.Text = 'OFF';
            app.P4_1 = uibutton(app.Input, 'state');
            app.P4_1.ValueChangedFcn = createCallbackFcn(app, @P4_1Changed, true);
            app.P4_1.Text = 'OFF';
            app.ArduinoPinLabel_1 = uilabel(app.Input);
            app.ArduinoPinLabel_1.Text = 'Arduino Pin #:';
            app.AP1_1 = uieditfield(app.Input, 'numeric');
            app.AP1_1.Value = 1;
            app.AP2_1 = uieditfield(app.Input, 'numeric');
            app.AP2_1.Value = 2;
            app.AP3_1 = uieditfield(app.Input, 'numeric');
            app.AP3_1.Value = 3;
            app.AP4_1 = uieditfield(app.Input, 'numeric');
            app.AP4_1.Value = 4;
            app.ArduinoPinLabel_2 = uilabel(app.Input);
            app.ArduinoPinLabel_2.Text = 'Arduino Pin #:';
            app.P5Lamp_1 = uilamp(app.Input);
            app.P6Lamp_1 = uilamp(app.Input);
            app.P7Lamp_1 = uilamp(app.Input);
            app.P8Lamp_1 = uilamp(app.Input);
            app.P5_1 = uibutton(app.Input, 'state');
            app.P5_1.ValueChangedFcn = createCallbackFcn(app, @P5_1ValueChanged, true);
            app.P5_1.Text = 'OFF';
            app.P6_1 = uibutton(app.Input, 'state');
            app.P6_1.ValueChangedFcn = createCallbackFcn(app, @P6_1ValueChanged, true);
            app.P6_1.Text = 'OFF';
            app.P7_1 = uibutton(app.Input, 'state');
            app.P7_1.ValueChangedFcn = createCallbackFcn(app, @P7_1ValueChanged, true);
            app.P7_1.Text = 'OFF';
            app.P8_1 = uibutton(app.Input, 'state');
            app.P8_1.ValueChangedFcn = createCallbackFcn(app, @P8_1ValueChanged, true);
            app.P8_1.Text = 'OFF';
            app.AP5_1 = uieditfield(app.Input, 'numeric');
            app.AP5_1.Value = 5;
            app.AP6_1 = uieditfield(app.Input, 'numeric');
            app.AP6_1.Value = 6;
            app.AP7_1 = uieditfield(app.Input, 'numeric');
            app.AP7_1.Value = 7;
            app.AP8_1 = uieditfield(app.Input, 'numeric');
            app.AP8_1.Value = 8;
            app.PinStatesLabel_1 = uilabel(app.Input);
            app.PinStatesLabel_1.Text = 'Pin States';
            app.P1Label_1 = uilabel(app.Input);
            app.P1Label_1.Text = '1';
            app.P2Label_1 = uilabel(app.Input);
            app.P2Label_1.Text = '2';
            app.P3Label_1 = uilabel(app.Input);
            app.P3Label_1.Text = '3';
            app.P4Label_1 = uilabel(app.Input);
            app.P4Label_1.Text = '4';
            app.P5Label_1 = uilabel(app.Input);
            app.P5Label_1.Text = '5';
            app.P6Label_1 = uilabel(app.Input);
            app.P6Label_1.Text = '6';
            app.P7Label_1 = uilabel(app.Input);
            app.P7Label_1.Text = '7';
            app.P8Label_1 = uilabel(app.Input);
            app.P8Label_1.Text = '8';
            app.PinReset_1 = uibutton(app.Input, 'push');
            app.PinReset_1.ButtonPushedFcn = createCallbackFcn(app, @PinReset_1ButtonPushed, true);
            app.PinReset_1.Text = 'Pin Reset';
            app.CV1Tab_1 = uitab(app.HP1_Group);
            app.CV1Tab_1.Title = 'CV1';
            app.Export_CV_1 = uibutton(app.CV1Tab_1, 'push');
            app.Export_CV_1.ButtonPushedFcn = createCallbackFcn(app, @Export_CV_1Pushed, true);
            app.Export_CV_1.Text = 'Export Figure';
            app.CV1_1 = uiaxes(app.CV1Tab_1);
            title(app.CV1_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV1_1, 'Voltage (V)')
            ylabel(app.CV1_1, 'Capacitance (pF)')
            app.Normalize_1 = uibutton(app.CV1Tab_1, 'state');
            app.Normalize_1.Text = 'Normalize';
            app.CV2_1 = uiaxes(app.CV1Tab_1);
            title(app.CV2_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV2_1, 'Voltage (V)')
            ylabel(app.CV2_1, 'Capacitance (pF)')
            app.CV3_1 = uiaxes(app.CV1Tab_1);
            title(app.CV3_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV3_1, 'Voltage (V)')
            ylabel(app.CV3_1, 'Capacitance (pF)')
            app.CV4_1 = uiaxes(app.CV1Tab_1);
            title(app.CV4_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV4_1, 'Voltage (V)')
            ylabel(app.CV4_1, 'Capacitance (pF)')
            app.CV2Tab_1 = uitab(app.HP1_Group);
            app.CV2Tab_1.Title = 'CV2';
            app.Export_CV_2_1 = uibutton(app.CV2Tab_1, 'push');
            app.Export_CV_2_1.Text = 'Export Figure';
            app.CV5_1 = uiaxes(app.CV2Tab_1);
            title(app.CV5_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV5_1, 'Voltage (V)')
            ylabel(app.CV5_1, 'Capacitance (pF)')
            app.Normalize_2_1 = uibutton(app.CV2Tab_1, 'state');
            app.Normalize_2_1.Text = 'Normalize';
            app.CV6_1 = uiaxes(app.CV2Tab_1);
            title(app.CV6_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV6_1, 'Voltage (V)')
            ylabel(app.CV6_1, 'Capacitance (pF)')
            app.CV7_1 = uiaxes(app.CV2Tab_1);
            title(app.CV7_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV7_1, 'Voltage (V)')
            ylabel(app.CV7_1, 'Capacitance (pF)')
            app.CV8_1 = uiaxes(app.CV2Tab_1);
            title(app.CV8_1, 'Capacitance Vs. Voltage')
            xlabel(app.CV8_1, 'Voltage (V)')
            ylabel(app.CV8_1, 'Capacitance (pF)')
            app.VFBFit1Tab_1 = uitab(app.HP1_Group);
            app.VFBFit1Tab_1.Title = 'VFB Fit 1';
            app.byC2_1_1 = uiaxes(app.VFBFit1Tab_1);
            title(app.byC2_1_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_1_1, 'Voltage (V)')
            ylabel(app.byC2_1_1, 'd^2(1/C^2)/d^2V')
            app.byC2_2_1 = uiaxes(app.VFBFit1Tab_1);
            title(app.byC2_2_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_2_1, 'Voltage (V)')
            ylabel(app.byC2_2_1, 'd^2(1/C^2)/d^2V')
            app.byC2_3_1 = uiaxes(app.VFBFit1Tab_1);
            title(app.byC2_3_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_3_1, 'Voltage (V)')
            ylabel(app.byC2_3_1, 'd^2(1/C^2)/d^2V')
            app.byC2_4_1 = uiaxes(app.VFBFit1Tab_1);
            title(app.byC2_4_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_4_1, 'Voltage (V)')
            ylabel(app.byC2_4_1, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_1 = uibutton(app.VFBFit1Tab_1, 'push');
            app.Export_byC2_1.ButtonPushedFcn = createCallbackFcn(app, @Export_byC2_1Pushed, true);
            app.Export_byC2_1.Text = 'Export Figure';
            app.DerPeaks_1 = uibutton(app.VFBFit1Tab_1, 'state');
            app.DerPeaks_1.ValueChangedFcn = createCallbackFcn(app, @DerPeaks_1Pressed, true);
            app.DerPeaks_1.Text = 'Derivative Peaks';
            app.DerPeaks_1.Value = true;
            app.UpdateFit_1 = uibutton(app.VFBFit1Tab_1, 'push');
            app.UpdateFit_1.ButtonPushedFcn = createCallbackFcn(app, @UpdateFit_1ButtonPushed, true);
            app.UpdateFit_1.Text = 'Update Fit';
            app.CFitMinEditFieldLabel = uilabel(app.VFBFit1Tab_1);
            app.CFitMinEditFieldLabel.Text = 'C Fit Min:';
            app.CFitMin_1 = uieditfield(app.VFBFit1Tab_1, 'numeric');
            app.CFitMin_1.Value = 5;
            app.FitRegion_1 = uibutton(app.VFBFit1Tab_1, 'state');
            app.FitRegion_1.Text = 'Fit Region';
            app.SmoothCheckBox_1 = uicheckbox(app.VFBFit1Tab_1);
            app.SmoothCheckBox_1.Text = 'Smooth';
            app.CFitMaxEditFieldLabel = uilabel(app.VFBFit1Tab_1);
            app.CFitMaxEditFieldLabel.Text = 'C Fit Max:';
            app.CFitMax_1 = uieditfield(app.VFBFit1Tab_1, 'numeric');
            app.CFitMax_1.Value = 12;
            app.VFBFit2Tab_1 = uitab(app.HP1_Group);
            app.VFBFit2Tab_1.Title = 'VFB Fit 2';
            app.byC2_5_1 = uiaxes(app.VFBFit2Tab_1);
            title(app.byC2_5_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_5_1, 'Voltage (V)')
            ylabel(app.byC2_5_1, 'd^2(1/C^2)/d^2V')
            app.byC2_6_1 = uiaxes(app.VFBFit2Tab_1);
            title(app.byC2_6_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_6_1, 'Voltage (V)')
            ylabel(app.byC2_6_1, 'd^2(1/C^2)/d^2V')
            app.byC2_7_1 = uiaxes(app.VFBFit2Tab_1);
            title(app.byC2_7_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_7_1, 'Voltage (V)')
            ylabel(app.byC2_7_1, 'd^2(1/C^2)/d^2V')
            app.byC2_8_1 = uiaxes(app.VFBFit2Tab_1);
            title(app.byC2_8_1, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_8_1, 'Voltage (V)')
            ylabel(app.byC2_8_1, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_2_1 = uibutton(app.VFBFit2Tab_1, 'push');
            app.Export_byC2_2_1.Text = 'Export Figure';
            app.DerPeaks_2_1 = uibutton(app.VFBFit2Tab_1, 'state');
            app.DerPeaks_2_1.Text = 'Derivative Peaks';
            app.DerPeaks_2_1.Value = true;
            app.SmoothCheckBox_2_1 = uicheckbox(app.VFBFit2Tab_1);
            app.SmoothCheckBox_2_1.Text = 'Smooth';
            app.UpdateFit_2_1 = uibutton(app.VFBFit2Tab_1, 'push');
            app.UpdateFit_2_1.Text = 'Update Fit';
            app.FitRegion_2_1 = uibutton(app.VFBFit2Tab_1, 'state');
            app.FitRegion_2_1.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_2 = uilabel(app.VFBFit2Tab_1);
            app.CFitMaxEditFieldLabel_2.Text = 'C Fit Max:';
            app.CFitMax_2_1 = uieditfield(app.VFBFit2Tab_1, 'numeric');
            app.CFitMax_2_1.Value = 12;
            app.CFitMinEditFieldLabel_2 = uilabel(app.VFBFit2Tab_1);
            app.CFitMinEditFieldLabel_2.Text = 'C Fit Min:';
            app.CFitMin_2_1 = uieditfield(app.VFBFit2Tab_1, 'numeric');
            app.CFitMin_2_1.Value = 5;
            app.ItTab_1 = uitab(app.HP1_Group);
            app.ItTab_1.Title = 'I-t';
            app.It_1 = uiaxes(app.ItTab_1);
            title(app.It_1, 'Current Vs. Time')
            xlabel(app.It_1, 'Time (hr)')
            ylabel(app.It_1, 'Current (A)')
            app.Export_It_1 = uibutton(app.ItTab_1, 'push');
            app.Export_It_1.ButtonPushedFcn = createCallbackFcn(app, @Export_It_1Button, true);
            app.Export_It_1.Text = 'Export Figure';
            app.VFBvsTimeTab_1 = uitab(app.HP1_Group);
            app.VFBvsTimeTab_1.Title = 'VFB vs. Time';
            app.ExportVFBtime_1 = uibutton(app.VFBvsTimeTab_1, 'push');
            app.ExportVFBtime_1.ButtonPushedFcn = createCallbackFcn(app, @ExportVFBtime_1Pushed, true);
            app.ExportVFBtime_1.Text = 'Export Figure';
            app.VFBtime_1 = uiaxes(app.VFBvsTimeTab_1);
            title(app.VFBtime_1, 'Flatband Voltage Vs. Time')
            xlabel(app.VFBtime_1, 'Time')
            ylabel(app.VFBtime_1, 'Flatband Voltage (V)')
            app.TempvsTimeTab_1 = uitab(app.HP1_Group);
            app.TempvsTimeTab_1.Title = 'Temperature vs. Time';
            app.TempTime_1 = uiaxes(app.TempvsTimeTab_1);
            title(app.TempTime_1, 'Temperature Vs. Time')
            xlabel(app.TempTime_1, 'Time (hr)')
            ylabel(app.TempTime_1, 'Temperature (C)')
            app.Export_TempTime_1 = uibutton(app.TempvsTimeTab_1, 'push');
            app.Export_TempTime_1.ButtonPushedFcn = createCallbackFcn(app, @Export_TempTime_1Button, true);
            app.Export_TempTime_1.Text = 'Export Figure';
            app.Hotplate2Tab = uitab(app.HPGUI_Group);
            app.Hotplate2Tab.Title = 'Hotplate 2';
            app.HP2_Group = uitabgroup(app.Hotplate2Tab);
            app.Input_2 = uitab(app.HP2_Group);
            app.Input_2.Title = 'Input';
            app.ImpedanceAnalyzerCVLabel_2 = uilabel(app.Input_2);
            app.ImpedanceAnalyzerCVLabel_2.Text = 'Impedance Analyzer (CV)';
            app.Freq_unit_2 = uidropdown(app.Input_2);
            app.Freq_unit_2.Items = {'KHz', 'MHz'};
            app.Freq_unit_2.Value = 'MHz';
            app.FrequencyLabel_2 = uilabel(app.Input_2);
            app.FrequencyLabel_2.Text = 'Frequency';
            app.Frequency_2 = uieditfield(app.Input_2, 'numeric');
            app.Frequency_2.Value = 1;
            app.PositiveVoltageVLabel_2 = uilabel(app.Input_2);
            app.PositiveVoltageVLabel_2.Text = 'Positive Voltage (V)';
            app.VPos_2 = uieditfield(app.Input_2, 'numeric');
            app.VPos_2.Value = 7;
            app.NegativeVoltageVLabel_2 = uilabel(app.Input_2);
            app.NegativeVoltageVLabel_2.Text = 'Negative Voltage (V)';
            app.VNeg_2 = uieditfield(app.Input_2, 'numeric');
            app.VNeg_2.Value = -7;
            app.VACmVpkpkEditFieldLabel_2 = uilabel(app.Input_2);
            app.VACmVpkpkEditFieldLabel_2.Text = 'VAC [mV(pk-pk)]';
            app.OSC_2 = uieditfield(app.Input_2, 'numeric');
            app.OSC_2.Value = 100;
            app.SweepSwitchLabel_2 = uilabel(app.Input_2);
            app.SweepSwitchLabel_2.Text = 'Sweep';
            app.Sweep_2 = uiswitch(app.Input_2, 'rocker');
            app.Sweep_2.Items = {'Down', 'Up'};
            app.Sweep_2.Value = 'Down';
            app.StepVoltagemVLabel_2 = uilabel(app.Input_2);
            app.StepVoltagemVLabel_2.Text = 'Step Voltage (mV)';
            app.VStep_2 = uieditfield(app.Input_2, 'numeric');
            app.VStep_2.Value = 100;
            app.ofAveragesDropDownLabel_2 = uilabel(app.Input_2);
            app.ofAveragesDropDownLabel_2.Text = '# of Averages';
            app.NOA_2 = uidropdown(app.Input_2);
            app.NOA_2.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.NOA_2.Value = '2';
            app.IntegrationTimeDropDownLabel_2 = uilabel(app.Input_2);
            app.IntegrationTimeDropDownLabel_2.Text = 'Integration Time';
            app.ITM_2 = uidropdown(app.Input_2);
            app.ITM_2.Items = {'500 us', '5 ms', '100 ms'};
            app.ITM_2.Value = '5 ms';
            app.ExperimentalFittingLabel_2 = uilabel(app.Input_2);
            app.ExperimentalFittingLabel_2.Text = 'Experimental/Fitting:';
            app.t_offset_unit_2 = uidropdown(app.Input_2);
            app.t_offset_unit_2.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_offset_unit_2.Value = 'sec';
            app.t_inc_unit_2 = uidropdown(app.Input_2);
            app.t_inc_unit_2.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_inc_unit_2.Value = 'sec';
            app.THKnmEditFieldLabel_2 = uilabel(app.Input_2);
            app.THKnmEditFieldLabel_2.Text = 'THK [nm]';
            app.THK_2 = uieditfield(app.Input_2, 'numeric');
            app.THK_2.Value = 76.96;
            app.HeatTempCLabel_2 = uilabel(app.Input_2);
            app.HeatTempCLabel_2.Text = 'Heat Temp [C]';
            app.TempH_2 = uieditfield(app.Input_2, 'numeric');
            app.TempH_2.Value = 26;
            app.StressVoltageVLabel_2 = uilabel(app.Input_2);
            app.StressVoltageVLabel_2.Text = 'Stress Voltage [V]';
            app.Bias_2 = uieditfield(app.Input_2, 'numeric');
            app.TimeOffsetLabel_2 = uilabel(app.Input_2);
            app.TimeOffsetLabel_2.Text = 'Time Offset';
            app.TimeOffset_2 = uieditfield(app.Input_2, 'numeric');
            app.TimeIncLabel_2 = uilabel(app.Input_2);
            app.TimeIncLabel_2.Text = 'Time Inc';
            app.dt_2 = uieditfield(app.Input_2, 'numeric');
            app.dt_2.Value = 5;
            app.TotalIterationsLabel_2 = uilabel(app.Input_2);
            app.TotalIterationsLabel_2.Text = '# Total Iterations';
            app.Iter_tot_2 = uieditfield(app.Input_2, 'numeric');
            app.Iter_tot_2.Value = 1;
            app.IterationsMeasureLabel_2 = uilabel(app.Input_2);
            app.IterationsMeasureLabel_2.Text = '# Iterations/Measure';
            app.IterM_2 = uieditfield(app.Input_2, 'numeric');
            app.IterM_2.Value = 1;
            app.ItDataPointsStressLabel_2 = uilabel(app.Input_2);
            app.ItDataPointsStressLabel_2.Text = '# I-t Data Points/Stress';
            app.Itpts_2 = uieditfield(app.Input_2, 'numeric');
            app.Itpts_2.Value = 5;
            app.PreStressVoltageVLabel_2 = uilabel(app.Input_2);
            app.PreStressVoltageVLabel_2.Text = 'PreStress Voltage [V]';
            app.PreBias_2 = uieditfield(app.Input_2, 'numeric');
            app.PreStressTimeminLabel_2 = uilabel(app.Input_2);
            app.PreStressTimeminLabel_2.Text = 'PreStress Time [min]';
            app.PreBiasTime_2 = uieditfield(app.Input_2, 'numeric');
            app.CoolTempCLabel_2 = uilabel(app.Input_2);
            app.CoolTempCLabel_2.Text = 'Cool Temp [C]';
            app.TempC_2 = uieditfield(app.Input_2, 'numeric');
            app.TempC_2.Value = 26;
            app.HeatWaitTimesLabel_2 = uilabel(app.Input_2);
            app.HeatWaitTimesLabel_2.Text = 'Heat Wait Time [s]';
            app.HeatTime_2 = uieditfield(app.Input_2, 'numeric');
            app.HeatTime_2.Value = 5;
            app.CoolWaitTimesLabel_2 = uilabel(app.Input_2);
            app.CoolWaitTimesLabel_2.Text = 'Cool Wait Time [s]';
            app.CoolTime_2 = uieditfield(app.Input_2, 'numeric');
            app.CoolTime_2.Value = 5;
            app.P1Lamp_2 = uilamp(app.Input_2);
            app.P2Lamp_2 = uilamp(app.Input_2);
            app.P3Lamp_2 = uilamp(app.Input_2);
            app.P4Lamp_2 = uilamp(app.Input_2);
            app.P1_2 = uibutton(app.Input_2, 'state');
            app.P1_2.Text = 'OFF';
            app.P2_2 = uibutton(app.Input_2, 'state');
            app.P2_2.Text = 'OFF';
            app.P3_2 = uibutton(app.Input_2, 'state');
            app.P3_2.Text = 'OFF';
            app.P4_2 = uibutton(app.Input_2, 'state');
            app.P4_2.Text = 'OFF';
            app.ArduinoPinLabel_1_2 = uilabel(app.Input_2);
            app.ArduinoPinLabel_1_2.Text = 'Arduino Pin #:';
            app.AP1_2 = uieditfield(app.Input_2, 'numeric');
            app.AP1_2.Value = 1;
            app.AP2_2 = uieditfield(app.Input_2, 'numeric');
            app.AP2_2.Value = 2;
            app.AP3_2 = uieditfield(app.Input_2, 'numeric');
            app.AP3_2.Value = 3;
            app.AP4_2 = uieditfield(app.Input_2, 'numeric');
            app.AP4_2.Value = 4;
            app.ArduinoPinLabel_2_2 = uilabel(app.Input_2);
            app.ArduinoPinLabel_2_2.Text = 'Arduino Pin #:';
            app.P5Lamp_2 = uilamp(app.Input_2);
            app.P6Lamp_2 = uilamp(app.Input_2);
            app.P7Lamp_2 = uilamp(app.Input_2);
            app.P8Lamp_2 = uilamp(app.Input_2);
            app.P5_2 = uibutton(app.Input_2, 'state');
            app.P5_2.Text = 'OFF';
            app.P6_2 = uibutton(app.Input_2, 'state');
            app.P6_2.Text = 'OFF';
            app.P7_2 = uibutton(app.Input_2, 'state');
            app.P7_2.Text = 'OFF';
            app.P8_2 = uibutton(app.Input_2, 'state');
            app.P8_2.Text = 'OFF';
            app.AP5_2 = uieditfield(app.Input_2, 'numeric');
            app.AP5_2.Value = 5;
            app.AP6_2 = uieditfield(app.Input_2, 'numeric');
            app.AP6_2.Value = 6;
            app.AP7_2 = uieditfield(app.Input_2, 'numeric');
            app.AP7_2.Value = 7;
            app.AP8_2 = uieditfield(app.Input_2, 'numeric');
            app.AP8_2.Value = 8;
            app.PinStatesLabel_2 = uilabel(app.Input_2);
            app.PinStatesLabel_2.Text = 'Pin States';
            app.P1Label_2 = uilabel(app.Input_2);
            app.P1Label_2.Text = '1';
            app.P2Label_2 = uilabel(app.Input_2);
            app.P2Label_2.Text = '2';
            app.P3Label_2 = uilabel(app.Input_2);
            app.P3Label_2.Text = '3';
            app.P4Label_2 = uilabel(app.Input_2);
            app.P4Label_2.Text = '4';
            app.P5Label_2 = uilabel(app.Input_2);
            app.P5Label_2.Text = '5';
            app.P6Label_2 = uilabel(app.Input_2);
            app.P6Label_2.Text = '6';
            app.P7Label_2 = uilabel(app.Input_2);
            app.P7Label_2.Text = '7';
            app.P8Label_2 = uilabel(app.Input_2);
            app.P8Label_2.Text = '8';
            app.PinReset_2 = uibutton(app.Input_2, 'push');
            app.PinReset_2.Text = 'Pin Reset';
            app.CV1Tab_2 = uitab(app.HP2_Group);
            app.CV1Tab_2.Title = 'CV1';
            app.Export_CV_1_2 = uibutton(app.CV1Tab_2, 'push');
            app.Export_CV_1_2.Text = 'Export Figure';
            app.CV1_2 = uiaxes(app.CV1Tab_2);
            title(app.CV1_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV1_2, 'Voltage (V)')
            ylabel(app.CV1_2, 'Capacitance (pF)')
            app.Normalize_1_2 = uibutton(app.CV1Tab_2, 'state');
            app.Normalize_1_2.Text = 'Normalize';
            app.CV2_2 = uiaxes(app.CV1Tab_2);
            title(app.CV2_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV2_2, 'Voltage (V)')
            ylabel(app.CV2_2, 'Capacitance (pF)')
            app.CV3_2 = uiaxes(app.CV1Tab_2);
            title(app.CV3_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV3_2, 'Voltage (V)')
            ylabel(app.CV3_2, 'Capacitance (pF)')
            app.CV4_2 = uiaxes(app.CV1Tab_2);
            title(app.CV4_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV4_2, 'Voltage (V)')
            ylabel(app.CV4_2, 'Capacitance (pF)')
            app.CV2Tab_2 = uitab(app.HP2_Group);
            app.CV2Tab_2.Title = 'CV2';
            app.Export_CV_2_2 = uibutton(app.CV2Tab_2, 'push');
            app.Export_CV_2_2.Text = 'Export Figure';
            app.CV5_2 = uiaxes(app.CV2Tab_2);
            title(app.CV5_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV5_2, 'Voltage (V)')
            ylabel(app.CV5_2, 'Capacitance (pF)')
            app.Normalize_2_2 = uibutton(app.CV2Tab_2, 'state');
            app.Normalize_2_2.Text = 'Normalize';
            app.CV6_2 = uiaxes(app.CV2Tab_2);
            title(app.CV6_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV6_2, 'Voltage (V)')
            ylabel(app.CV6_2, 'Capacitance (pF)')
            app.CV7_2 = uiaxes(app.CV2Tab_2);
            title(app.CV7_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV7_2, 'Voltage (V)')
            ylabel(app.CV7_2, 'Capacitance (pF)')
            app.CV8_2 = uiaxes(app.CV2Tab_2);
            title(app.CV8_2, 'Capacitance Vs. Voltage')
            xlabel(app.CV8_2, 'Voltage (V)')
            ylabel(app.CV8_2, 'Capacitance (pF)')
            app.VFBFit1Tab_2 = uitab(app.HP2_Group);
            app.VFBFit1Tab_2.Title = 'VFB Fit 1';
            app.byC2_1_2 = uiaxes(app.VFBFit1Tab_2);
            title(app.byC2_1_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_1_2, 'Voltage (V)')
            ylabel(app.byC2_1_2, 'd^2(1/C^2)/d^2V')
            app.byC2_2_2 = uiaxes(app.VFBFit1Tab_2);
            title(app.byC2_2_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_2_2, 'Voltage (V)')
            ylabel(app.byC2_2_2, 'd^2(1/C^2)/d^2V')
            app.byC2_3_2 = uiaxes(app.VFBFit1Tab_2);
            title(app.byC2_3_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_3_2, 'Voltage (V)')
            ylabel(app.byC2_3_2, 'd^2(1/C^2)/d^2V')
            app.byC2_4_2 = uiaxes(app.VFBFit1Tab_2);
            title(app.byC2_4_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_4_2, 'Voltage (V)')
            ylabel(app.byC2_4_2, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_1_2 = uibutton(app.VFBFit1Tab_2, 'push');
            app.Export_byC2_1_2.Text = 'Export Figure';
            app.DerPeaks_1_2 = uibutton(app.VFBFit1Tab_2, 'state');
            app.DerPeaks_1_2.Text = 'Derivative Peaks';
            app.DerPeaks_1_2.Value = true;
            app.UpdateFit_1_2 = uibutton(app.VFBFit1Tab_2, 'push');
            app.UpdateFit_1_2.Text = 'Update Fit';
            app.CFitMinEditFieldLabel_3 = uilabel(app.VFBFit1Tab_2);
            app.CFitMinEditFieldLabel_3.Text = 'C Fit Min:';
            app.CFitMin_1_2 = uieditfield(app.VFBFit1Tab_2, 'numeric');
            app.CFitMin_1_2.Value = 5;
            app.FitRegion_1_2 = uibutton(app.VFBFit1Tab_2, 'state');
            app.FitRegion_1_2.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_3 = uilabel(app.VFBFit1Tab_2);
            app.CFitMaxEditFieldLabel_3.Text = 'C Fit Max:';
            app.CFitMax_1_2 = uieditfield(app.VFBFit1Tab_2, 'numeric');
            app.CFitMax_1_2.Value = 12;
            app.SmoothCheckBox_3 = uicheckbox(app.VFBFit1Tab_2);
            app.SmoothCheckBox_3.Text = 'Smooth';
            app.VFBFit2Tab_2 = uitab(app.HP2_Group);
            app.VFBFit2Tab_2.Title = 'VFB Fit 2';
            app.byC2_5_2 = uiaxes(app.VFBFit2Tab_2);
            title(app.byC2_5_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_5_2, 'Voltage (V)')
            ylabel(app.byC2_5_2, 'd^2(1/C^2)/d^2V')
            app.byC2_6_2 = uiaxes(app.VFBFit2Tab_2);
            title(app.byC2_6_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_6_2, 'Voltage (V)')
            ylabel(app.byC2_6_2, 'd^2(1/C^2)/d^2V')
            app.byC2_7_2 = uiaxes(app.VFBFit2Tab_2);
            title(app.byC2_7_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_7_2, 'Voltage (V)')
            ylabel(app.byC2_7_2, 'd^2(1/C^2)/d^2V')
            app.byC2_8_2 = uiaxes(app.VFBFit2Tab_2);
            title(app.byC2_8_2, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_8_2, 'Voltage (V)')
            ylabel(app.byC2_8_2, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_2_2 = uibutton(app.VFBFit2Tab_2, 'push');
            app.Export_byC2_2_2.Text = 'Export Figure';
            app.DerPeaks_2_2 = uibutton(app.VFBFit2Tab_2, 'state');
            app.DerPeaks_2_2.Text = 'Derivative Peaks';
            app.DerPeaks_2_2.Value = true;
            app.SmoothCheckBox_4 = uicheckbox(app.VFBFit2Tab_2);
            app.SmoothCheckBox_4.Text = 'Smooth';
            app.UpdateFit_2_2 = uibutton(app.VFBFit2Tab_2, 'push');
            app.UpdateFit_2_2.Text = 'Update Fit';
            app.FitRegion_2_2 = uibutton(app.VFBFit2Tab_2, 'state');
            app.FitRegion_2_2.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_4 = uilabel(app.VFBFit2Tab_2);
            app.CFitMaxEditFieldLabel_4.Text = 'C Fit Max:';
            app.CFitMax_2_2 = uieditfield(app.VFBFit2Tab_2, 'numeric');
            app.CFitMax_2_2.Value = 12;
            app.CFitMinEditFieldLabel_4 = uilabel(app.VFBFit2Tab_2);
            app.CFitMinEditFieldLabel_4.Text = 'C Fit Min:';
            app.CFitMin_4 = uieditfield(app.VFBFit2Tab_2, 'numeric');
            app.CFitMin_4.Value = 5;
            app.ItTab_2 = uitab(app.HP2_Group);
            app.ItTab_2.Title = 'I-t';
            app.It_2 = uiaxes(app.ItTab_2);
            title(app.It_2, 'Current Vs. Time')
            xlabel(app.It_2, 'Time (hr)')
            ylabel(app.It_2, 'Current (A)')
            app.Export_It_2 = uibutton(app.ItTab_2, 'push');
            app.Export_It_2.Text = 'Export Figure';
            app.VFBvsTimeTab_2 = uitab(app.HP2_Group);
            app.VFBvsTimeTab_2.Title = 'VFB vs. Time';
            app.ExportVFBtime_2 = uibutton(app.VFBvsTimeTab_2, 'push');
            app.ExportVFBtime_2.Text = 'Export Figure';
            app.VFBtime_2 = uiaxes(app.VFBvsTimeTab_2);
            title(app.VFBtime_2, 'Flatband Voltage Vs. Time')
            xlabel(app.VFBtime_2, 'Time')
            ylabel(app.VFBtime_2, 'Flatband Voltage (V)')
            app.TempvsTimeTab_2 = uitab(app.HP2_Group);
            app.TempvsTimeTab_2.Title = 'Temperature vs. Time';
            app.TempTime_2 = uiaxes(app.TempvsTimeTab_2);
            title(app.TempTime_2, 'Temperature Vs. Time')
            xlabel(app.TempTime_2, 'Time (hr)')
            ylabel(app.TempTime_2, 'Temperature (C)')
            app.Export_TempTime_2 = uibutton(app.TempvsTimeTab_2, 'push');
            app.Export_TempTime_2.Text = 'Export Figure';
            app.HP3_Tab = uitab(app.HPGUI_Group);
            app.HP3_Tab.Title = 'Hotplate 3';
            app.HP3_Group = uitabgroup(app.HP3_Tab);
            app.Input_3 = uitab(app.HP3_Group);
            app.Input_3.Title = 'Input';
            app.ImpedanceAnalyzerCVLabel_3 = uilabel(app.Input_3);
            app.ImpedanceAnalyzerCVLabel_3.Text = 'Impedance Analyzer (CV)';
            app.Freq_unit_3 = uidropdown(app.Input_3);
            app.Freq_unit_3.Items = {'KHz', 'MHz'};
            app.Freq_unit_3.Value = 'MHz';
            app.FrequencyLabel_3 = uilabel(app.Input_3);
            app.FrequencyLabel_3.Text = 'Frequency';
            app.Frequency_3 = uieditfield(app.Input_3, 'numeric');
            app.Frequency_3.Value = 1;
            app.PositiveVoltageVLabel_3 = uilabel(app.Input_3);
            app.PositiveVoltageVLabel_3.Text = 'Positive Voltage (V)';
            app.VPos_3 = uieditfield(app.Input_3, 'numeric');
            app.VPos_3.Value = 7;
            app.NegativeVoltageVLabel_3 = uilabel(app.Input_3);
            app.NegativeVoltageVLabel_3.Text = 'Negative Voltage (V)';
            app.VNeg_3 = uieditfield(app.Input_3, 'numeric');
            app.VNeg_3.Value = -7;
            app.VACmVpkpkEditFieldLabel_3 = uilabel(app.Input_3);
            app.VACmVpkpkEditFieldLabel_3.Text = 'VAC [mV(pk-pk)]';
            app.OSC_3 = uieditfield(app.Input_3, 'numeric');
            app.OSC_3.Value = 100;
            app.SweepSwitchLabel_3 = uilabel(app.Input_3);
            app.SweepSwitchLabel_3.Text = 'Sweep';
            app.Sweep_3 = uiswitch(app.Input_3, 'rocker');
            app.Sweep_3.Items = {'Down', 'Up'};
            app.Sweep_3.Value = 'Down';
            app.StepVoltagemVLabel_3 = uilabel(app.Input_3);
            app.StepVoltagemVLabel_3.Text = 'Step Voltage (mV)';
            app.VStep_3 = uieditfield(app.Input_3, 'numeric');
            app.VStep_3.Value = 100;
            app.ofAveragesDropDownLabel_3 = uilabel(app.Input_3);
            app.ofAveragesDropDownLabel_3.Text = '# of Averages';
            app.NOA_3 = uidropdown(app.Input_3);
            app.NOA_3.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.NOA_3.Value = '2';
            app.IntegrationTimeDropDownLabel_3 = uilabel(app.Input_3);
            app.IntegrationTimeDropDownLabel_3.Text = 'Integration Time';
            app.ITM_3 = uidropdown(app.Input_3);
            app.ITM_3.Items = {'500 us', '5 ms', '100 ms'};
            app.ITM_3.Value = '5 ms';
            app.ExperimentalFittingLabel_3 = uilabel(app.Input_3);
            app.ExperimentalFittingLabel_3.Text = 'Experimental/Fitting:';
            app.t_offset_unit_3 = uidropdown(app.Input_3);
            app.t_offset_unit_3.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_offset_unit_3.Value = 'sec';
            app.t_inc_unit_3 = uidropdown(app.Input_3);
            app.t_inc_unit_3.Items = {'sec', 'min', 'hrs', 'days'};
            app.t_inc_unit_3.Value = 'sec';
            app.THKnmEditFieldLabel_3 = uilabel(app.Input_3);
            app.THKnmEditFieldLabel_3.Text = 'THK [nm]';
            app.THK_3 = uieditfield(app.Input_3, 'numeric');
            app.THK_3.Value = 76.96;
            app.HeatTempCLabel_3 = uilabel(app.Input_3);
            app.HeatTempCLabel_3.Text = 'Heat Temp [C]';
            app.TempH_3 = uieditfield(app.Input_3, 'numeric');
            app.TempH_3.Value = 26;
            app.StressVoltageVLabel_3 = uilabel(app.Input_3);
            app.StressVoltageVLabel_3.Text = 'Stress Voltage [V]';
            app.Bias_3 = uieditfield(app.Input_3, 'numeric');
            app.TimeOffsetLabel_3 = uilabel(app.Input_3);
            app.TimeOffsetLabel_3.Text = 'Time Offset';
            app.TimeOffset_3 = uieditfield(app.Input_3, 'numeric');
            app.TimeIncLabel_3 = uilabel(app.Input_3);
            app.TimeIncLabel_3.Text = 'Time Inc';
            app.dt_3 = uieditfield(app.Input_3, 'numeric');
            app.dt_3.Value = 5;
            app.TotalIterationsLabel_3 = uilabel(app.Input_3);
            app.TotalIterationsLabel_3.Text = '# Total Iterations';
            app.Iter_tot_3 = uieditfield(app.Input_3, 'numeric');
            app.Iter_tot_3.Value = 1;
            app.IterationsMeasureLabel_3 = uilabel(app.Input_3);
            app.IterationsMeasureLabel_3.Text = '# Iterations/Measure';
            app.IterM_3 = uieditfield(app.Input_3, 'numeric');
            app.IterM_3.Value = 1;
            app.ItDataPointsStressLabel_3 = uilabel(app.Input_3);
            app.ItDataPointsStressLabel_3.Text = '# I-t Data Points/Stress';
            app.Itpts_3 = uieditfield(app.Input_3, 'numeric');
            app.Itpts_3.Value = 5;
            app.PreStressVoltageVLabel_3 = uilabel(app.Input_3);
            app.PreStressVoltageVLabel_3.Text = 'PreStress Voltage [V]';
            app.PreBias_3 = uieditfield(app.Input_3, 'numeric');
            app.PreStressTimeminLabel_3 = uilabel(app.Input_3);
            app.PreStressTimeminLabel_3.Text = 'PreStress Time [min]';
            app.PreBiasTime_3 = uieditfield(app.Input_3, 'numeric');
            app.CoolTempCLabel_3 = uilabel(app.Input_3);
            app.CoolTempCLabel_3.Text = 'Cool Temp [C]';
            app.TempC_3 = uieditfield(app.Input_3, 'numeric');
            app.TempC_3.Value = 26;
            app.HeatWaitTimesLabel_3 = uilabel(app.Input_3);
            app.HeatWaitTimesLabel_3.Text = 'Heat Wait Time [s]';
            app.HeatTime_3 = uieditfield(app.Input_3, 'numeric');
            app.HeatTime_3.Value = 5;
            app.CoolWaitTimesLabel_3 = uilabel(app.Input_3);
            app.CoolWaitTimesLabel_3.Text = 'Cool Wait Time [s]';
            app.CoolTime_3 = uieditfield(app.Input_3, 'numeric');
            app.CoolTime_3.Value = 5;
            app.P1Lamp_3 = uilamp(app.Input_3);
            app.P2Lamp_3 = uilamp(app.Input_3);
            app.P3Lamp_3 = uilamp(app.Input_3);
            app.P4Lamp_3 = uilamp(app.Input_3);
            app.P1_3 = uibutton(app.Input_3, 'state');
            app.P1_3.Text = 'OFF';
            app.P2_3 = uibutton(app.Input_3, 'state');
            app.P2_3.Text = 'OFF';
            app.P3_3 = uibutton(app.Input_3, 'state');
            app.P3_3.Text = 'OFF';
            app.P4_3 = uibutton(app.Input_3, 'state');
            app.P4_3.Text = 'OFF';
            app.ArduinoPinLabel_1_3 = uilabel(app.Input_3);
            app.ArduinoPinLabel_1_3.Text = 'Arduino Pin #:';
            app.AP1_3 = uieditfield(app.Input_3, 'numeric');
            app.AP1_3.Value = 1;
            app.AP2_3 = uieditfield(app.Input_3, 'numeric');
            app.AP2_3.Value = 2;
            app.AP3_3 = uieditfield(app.Input_3, 'numeric');
            app.AP3_3.Value = 3;
            app.AP4_3 = uieditfield(app.Input_3, 'numeric');
            app.AP4_3.Value = 4;
            app.ArduinoPinLabel_2_3 = uilabel(app.Input_3);
            app.ArduinoPinLabel_2_3.Text = 'Arduino Pin #:';
            app.P5Lamp_3 = uilamp(app.Input_3);
            app.P6Lamp_3 = uilamp(app.Input_3);
            app.P7Lamp_3 = uilamp(app.Input_3);
            app.P8Lamp_3 = uilamp(app.Input_3);
            app.P5_3 = uibutton(app.Input_3, 'state');
            app.P5_3.Text = 'OFF';
            app.P6_3 = uibutton(app.Input_3, 'state');
            app.P6_3.Text = 'OFF';
            app.P7_3 = uibutton(app.Input_3, 'state');
            app.P7_3.Text = 'OFF';
            app.P8_3 = uibutton(app.Input_3, 'state');
            app.P8_3.Text = 'OFF';
            app.AP5_3 = uieditfield(app.Input_3, 'numeric');
            app.AP5_3.Value = 5;
            app.AP6_3 = uieditfield(app.Input_3, 'numeric');
            app.AP6_3.Value = 6;
            app.AP7_3 = uieditfield(app.Input_3, 'numeric');
            app.AP7_3.Value = 7;
            app.AP8_3 = uieditfield(app.Input_3, 'numeric');
            app.AP8_3.Value = 8;
            app.PinStatesLabel_3 = uilabel(app.Input_3);
            app.PinStatesLabel_3.Text = 'Pin States';
            app.P1Label_3 = uilabel(app.Input_3);
            app.P1Label_3.Text = '1';
            app.P2Label_3 = uilabel(app.Input_3);
            app.P2Label_3.Text = '2';
            app.P3Label_3 = uilabel(app.Input_3);
            app.P3Label_3.Text = '3';
            app.P4Label_3 = uilabel(app.Input_3);
            app.P4Label_3.Text = '4';
            app.P5Label_3 = uilabel(app.Input_3);
            app.P5Label_3.Text = '5';
            app.P6Label_3 = uilabel(app.Input_3);
            app.P6Label_3.Text = '6';
            app.P7Label_3 = uilabel(app.Input_3);
            app.P7Label_3.Text = '7';
            app.P8Label_3 = uilabel(app.Input_3);
            app.P8Label_3.Text = '8';
            app.PinReset_3 = uibutton(app.Input_3, 'push');
            app.PinReset_3.Text = 'Pin Reset';
            app.CV1Tab_3 = uitab(app.HP3_Group);
            app.CV1Tab_3.Title = 'CV1';
            app.Export_CV_1_3 = uibutton(app.CV1Tab_3, 'push');
            app.Export_CV_1_3.Text = 'Export Figure';
            app.CV1_3 = uiaxes(app.CV1Tab_3);
            title(app.CV1_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV1_3, 'Voltage (V)')
            ylabel(app.CV1_3, 'Capacitance (pF)')
            app.Normalize_1_3 = uibutton(app.CV1Tab_3, 'state');
            app.Normalize_1_3.Text = 'Normalize';
            app.CV2_3 = uiaxes(app.CV1Tab_3);
            title(app.CV2_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV2_3, 'Voltage (V)')
            ylabel(app.CV2_3, 'Capacitance (pF)')
            app.CV3_3 = uiaxes(app.CV1Tab_3);
            title(app.CV3_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV3_3, 'Voltage (V)')
            ylabel(app.CV3_3, 'Capacitance (pF)')
            app.CV4_3 = uiaxes(app.CV1Tab_3);
            title(app.CV4_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV4_3, 'Voltage (V)')
            ylabel(app.CV4_3, 'Capacitance (pF)')
            app.CV2Tab_3 = uitab(app.HP3_Group);
            app.CV2Tab_3.Title = 'CV2';
            app.Export_CV_2_3 = uibutton(app.CV2Tab_3, 'push');
            app.Export_CV_2_3.Text = 'Export Figure';
            app.CV5_3 = uiaxes(app.CV2Tab_3);
            title(app.CV5_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV5_3, 'Voltage (V)')
            ylabel(app.CV5_3, 'Capacitance (pF)')
            app.Normalize_2_3 = uibutton(app.CV2Tab_3, 'state');
            app.Normalize_2_3.Text = 'Normalize';
            app.CV6_3 = uiaxes(app.CV2Tab_3);
            title(app.CV6_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV6_3, 'Voltage (V)')
            ylabel(app.CV6_3, 'Capacitance (pF)')
            app.CV7_3 = uiaxes(app.CV2Tab_3);
            title(app.CV7_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV7_3, 'Voltage (V)')
            ylabel(app.CV7_3, 'Capacitance (pF)')
            app.CV8_3 = uiaxes(app.CV2Tab_3);
            title(app.CV8_3, 'Capacitance Vs. Voltage')
            xlabel(app.CV8_3, 'Voltage (V)')
            ylabel(app.CV8_3, 'Capacitance (pF)')
            app.VFBFit1Tab_3 = uitab(app.HP3_Group);
            app.VFBFit1Tab_3.Title = 'VFB Fit 1';
            app.byC2_1_3 = uiaxes(app.VFBFit1Tab_3);
            title(app.byC2_1_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_1_3, 'Voltage (V)')
            ylabel(app.byC2_1_3, 'd^2(1/C^2)/d^2V')
            app.byC2_2_3 = uiaxes(app.VFBFit1Tab_3);
            title(app.byC2_2_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_2_3, 'Voltage (V)')
            ylabel(app.byC2_2_3, 'd^2(1/C^2)/d^2V')
            app.byC2_3_3 = uiaxes(app.VFBFit1Tab_3);
            title(app.byC2_3_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_3_3, 'Voltage (V)')
            ylabel(app.byC2_3_3, 'd^2(1/C^2)/d^2V')
            app.byC2_4_3 = uiaxes(app.VFBFit1Tab_3);
            title(app.byC2_4_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_4_3, 'Voltage (V)')
            ylabel(app.byC2_4_3, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_1_3 = uibutton(app.VFBFit1Tab_3, 'push');
            app.Export_byC2_1_3.Text = 'Export Figure';
            app.DerPeaks_1_3 = uibutton(app.VFBFit1Tab_3, 'state');
            app.DerPeaks_1_3.Text = 'Derivative Peaks';
            app.DerPeaks_1_3.Value = true;
            app.UpdateFit_1_3 = uibutton(app.VFBFit1Tab_3, 'push');
            app.UpdateFit_1_3.Text = 'Update Fit';
            app.CFitMinEditFieldLabel_5 = uilabel(app.VFBFit1Tab_3);
            app.CFitMinEditFieldLabel_5.Text = 'C Fit Min:';
            app.CFitMin_1_3 = uieditfield(app.VFBFit1Tab_3, 'numeric');
            app.CFitMin_1_3.Value = 5;
            app.FitRegion_1_3 = uibutton(app.VFBFit1Tab_3, 'state');
            app.FitRegion_1_3.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_5 = uilabel(app.VFBFit1Tab_3);
            app.CFitMaxEditFieldLabel_5.Text = 'C Fit Max:';
            app.CFitMax_1_3 = uieditfield(app.VFBFit1Tab_3, 'numeric');
            app.CFitMax_1_3.Value = 12;
            app.SmoothCheckBox_5 = uicheckbox(app.VFBFit1Tab_3);
            app.SmoothCheckBox_5.Text = 'Smooth';
            app.VFBFit2Tab_3 = uitab(app.HP3_Group);
            app.VFBFit2Tab_3.Title = 'VFB Fit 2';
            app.byC2_5_3 = uiaxes(app.VFBFit2Tab_3);
            title(app.byC2_5_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_5_3, 'Voltage (V)')
            ylabel(app.byC2_5_3, 'd^2(1/C^2)/d^2V')
            app.byC2_6_3 = uiaxes(app.VFBFit2Tab_3);
            title(app.byC2_6_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_6_3, 'Voltage (V)')
            ylabel(app.byC2_6_3, 'd^2(1/C^2)/d^2V')
            app.byC2_7_3 = uiaxes(app.VFBFit2Tab_3);
            title(app.byC2_7_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_7_3, 'Voltage (V)')
            ylabel(app.byC2_7_3, 'd^2(1/C^2)/d^2V')
            app.byC2_8_3 = uiaxes(app.VFBFit2Tab_3);
            title(app.byC2_8_3, 'd^2(1/C^2)/d^2V Vs. Voltage')
            xlabel(app.byC2_8_3, 'Voltage (V)')
            ylabel(app.byC2_8_3, 'd^2(1/C^2)/d^2V')
            app.Export_byC2_2_3 = uibutton(app.VFBFit2Tab_3, 'push');
            app.Export_byC2_2_3.Text = 'Export Figure';
            app.DerPeaks_2_3 = uibutton(app.VFBFit2Tab_3, 'state');
            app.DerPeaks_2_3.Text = 'Derivative Peaks';
            app.DerPeaks_2_3.Value = true;
            app.SmoothCheckBox_6 = uicheckbox(app.VFBFit2Tab_3);
            app.SmoothCheckBox_6.Text = 'Smooth';
            app.UpdateFit_2_3 = uibutton(app.VFBFit2Tab_3, 'push');
            app.UpdateFit_2_3.Text = 'Update Fit';
            app.FitRegion_2_3 = uibutton(app.VFBFit2Tab_3, 'state');
            app.FitRegion_2_3.Text = 'Fit Region';
            app.CFitMaxEditFieldLabel_6 = uilabel(app.VFBFit2Tab_3);
            app.CFitMaxEditFieldLabel_6.Text = 'C Fit Max:';
            app.CFitMax_2_3 = uieditfield(app.VFBFit2Tab_3, 'numeric');
            app.CFitMax_2_3.Value = 12;
            app.CFitMinEditFieldLabel_6 = uilabel(app.VFBFit2Tab_3);
            app.CFitMinEditFieldLabel_6.Text = 'C Fit Min:';
            app.CFitMin_5 = uieditfield(app.VFBFit2Tab_3, 'numeric');
            app.CFitMin_5.Value = 5;
            app.ItTab_3 = uitab(app.HP3_Group);
            app.ItTab_3.Title = 'I-t';
            app.It_3 = uiaxes(app.ItTab_3);
            title(app.It_3, 'Current Vs. Time')
            xlabel(app.It_3, 'Time (hr)')
            ylabel(app.It_3, 'Current (A)')
            app.Export_It_3 = uibutton(app.ItTab_3, 'push');
            app.Export_It_3.Text = 'Export Figure';
            app.VFBvsTimeTab_3 = uitab(app.HP3_Group);
            app.VFBvsTimeTab_3.Title = 'VFB vs. Time';
            app.ExportVFBtime_3 = uibutton(app.VFBvsTimeTab_3, 'push');
            app.ExportVFBtime_3.Text = 'Export Figure';
            app.VFBtime_3 = uiaxes(app.VFBvsTimeTab_3);
            title(app.VFBtime_3, 'Flatband Voltage Vs. Time')
            xlabel(app.VFBtime_3, 'Time')
            ylabel(app.VFBtime_3, 'Flatband Voltage (V)')
            app.TempvsTimeTab_3 = uitab(app.HP3_Group);
            app.TempvsTimeTab_3.Title = 'Temperature vs. Time';
            app.TempTime_3 = uiaxes(app.TempvsTimeTab_3);
            title(app.TempTime_3, 'Temperature Vs. Time')
            xlabel(app.TempTime_3, 'Time (hr)')
            ylabel(app.TempTime_3, 'Temperature (C)')
            app.Export_TempTime_3 = uibutton(app.TempvsTimeTab_3, 'push');
            app.Export_TempTime_3.Text = 'Export Figure';
            app.SaveDataAsTextAreaLabel = uilabel(app.UIFigure);
            app.SaveDataAsTextAreaLabel.Text = 'Save Name:';
            app.DataFileName = uitextarea(app.UIFigure);
            app.DataFileName.Value = {'test'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = InSitu_BST_SoftwareV8

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end